classdef Scan_Init_3R < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        ScanTab               matlab.ui.container.Tab
        ParameterPanel        matlab.ui.container.Panel
        ChannelLabel          matlab.ui.control.Label
        FromLabel             matlab.ui.control.Label
        XCheckBox             matlab.ui.control.CheckBox
        xChannel              matlab.ui.control.Button
        xFrom                 matlab.ui.control.NumericEditField
        StepLabel             matlab.ui.control.Label
        xStep                 matlab.ui.control.NumericEditField
        ToLabel               matlab.ui.control.Label
        xTo                   matlab.ui.control.NumericEditField
        YCheckBox             matlab.ui.control.CheckBox
        yChannel              matlab.ui.control.Button
        yFrom                 matlab.ui.control.NumericEditField
        yStep                 matlab.ui.control.NumericEditField
        yTo                   matlab.ui.control.NumericEditField
        DelayLabel            matlab.ui.control.Label
        xDelay                matlab.ui.control.NumericEditField
        yDelay                matlab.ui.control.NumericEditField
        RepeatLabel           matlab.ui.control.Label
        xRepeat               matlab.ui.control.NumericEditField
        yRepeat               matlab.ui.control.NumericEditField
        ZCheckBox             matlab.ui.control.CheckBox
        zChannel              matlab.ui.control.Button
        zFrom                 matlab.ui.control.NumericEditField
        zStep                 matlab.ui.control.NumericEditField
        zTo                   matlab.ui.control.NumericEditField
        zDelay                matlab.ui.control.NumericEditField
        zRepeat               matlab.ui.control.NumericEditField
        ReadPanel             matlab.ui.container.Panel
        ChangeFilePathButton  matlab.ui.control.Button
        FilePath              matlab.ui.control.EditField
        Read                  matlab.ui.control.Button
        FilePathLabel         matlab.ui.control.Label
        ReadLabel             matlab.ui.control.Label
        DisableLabel          matlab.ui.control.Label
        Button                matlab.ui.container.Panel
        HoldStateButton       matlab.ui.control.StateButton
        StartButton           matlab.ui.control.StateButton
        PauseButton           matlab.ui.control.StateButton
        InitialTab            matlab.ui.container.Tab
        InstrDropDownLabel    matlab.ui.control.Label
        InstrDropDown         matlab.ui.control.DropDown
        Lockin1Panel          matlab.ui.container.Panel
        A1EditField           matlab.ui.control.NumericEditField
        A1CheckBox            matlab.ui.control.CheckBox
        A2EditField           matlab.ui.control.NumericEditField
        A2CheckBox            matlab.ui.control.CheckBox
        A3EditField           matlab.ui.control.NumericEditField
        A3CheckBox            matlab.ui.control.CheckBox
        A4EditField           matlab.ui.control.NumericEditField
        A4CheckBox            matlab.ui.control.CheckBox
        Amp1EditField         matlab.ui.control.NumericEditField
        Amp1CheckBox          matlab.ui.control.CheckBox
        Freq1EditField        matlab.ui.control.NumericEditField
        Freq1CheckBox         matlab.ui.control.CheckBox
        X1EditField           matlab.ui.control.NumericEditField
        X1CheckBox            matlab.ui.control.CheckBox
        Sens1CheckBox         matlab.ui.control.CheckBox
        Sens1DropDown         matlab.ui.control.DropDown
        All1CheckBox          matlab.ui.control.CheckBox
        ReadButton            matlab.ui.control.Button
        InitButton            matlab.ui.control.Button
        StopButton_2          matlab.ui.control.Button
        Lockin2Panel          matlab.ui.container.Panel
        B1EditField           matlab.ui.control.NumericEditField
        B1CheckBox            matlab.ui.control.CheckBox
        B2EditField           matlab.ui.control.NumericEditField
        B2CheckBox            matlab.ui.control.CheckBox
        B3EditField           matlab.ui.control.NumericEditField
        B3CheckBox            matlab.ui.control.CheckBox
        B4EditField           matlab.ui.control.NumericEditField
        B4CheckBox            matlab.ui.control.CheckBox
        Amp2EditField         matlab.ui.control.NumericEditField
        Amp2CheckBox          matlab.ui.control.CheckBox
        Freq2EditField        matlab.ui.control.NumericEditField
        Freq2CheckBox         matlab.ui.control.CheckBox
        X2EditField           matlab.ui.control.NumericEditField
        X2CheckBox            matlab.ui.control.CheckBox
        Sens2CheckBox         matlab.ui.control.CheckBox
        Sens2DropDown         matlab.ui.control.DropDown
        All2CheckBox          matlab.ui.control.CheckBox
        ItekPanel             matlab.ui.container.Panel
        U1EditField           matlab.ui.control.NumericEditField
        U1CheckBox            matlab.ui.control.CheckBox
        All3CheckBox          matlab.ui.control.CheckBox
        U2EditField           matlab.ui.control.NumericEditField
        U2CheckBox            matlab.ui.control.CheckBox
        U3EditField           matlab.ui.control.NumericEditField
        U3CheckBox            matlab.ui.control.CheckBox
        U4EditField           matlab.ui.control.NumericEditField
        U4CheckBox            matlab.ui.control.CheckBox
        U5EditField           matlab.ui.control.NumericEditField
        U5CheckBox            matlab.ui.control.CheckBox
        U6EditField           matlab.ui.control.NumericEditField
        U6CheckBox            matlab.ui.control.CheckBox
        U7EditField           matlab.ui.control.NumericEditField
        U7CheckBox            matlab.ui.control.CheckBox
        U8EditField           matlab.ui.control.NumericEditField
        U8CheckBox            matlab.ui.control.CheckBox
        U9EditField           matlab.ui.control.NumericEditField
        U9CheckBox            matlab.ui.control.CheckBox
        U10EditField          matlab.ui.control.NumericEditField
        U10CheckBox           matlab.ui.control.CheckBox
        U11EditField          matlab.ui.control.NumericEditField
        U11CheckBox           matlab.ui.control.CheckBox
        U12EditField          matlab.ui.control.NumericEditField
        U12CheckBox           matlab.ui.control.CheckBox
        U13EditField          matlab.ui.control.NumericEditField
        U13CheckBox           matlab.ui.control.CheckBox
        U14EditField          matlab.ui.control.NumericEditField
        U14CheckBox           matlab.ui.control.CheckBox
        U15EditField          matlab.ui.control.NumericEditField
        U15CheckBox           matlab.ui.control.CheckBox
        U16EditField          matlab.ui.control.NumericEditField
        U16CheckBox           matlab.ui.control.CheckBox
        Ips3Panel             matlab.ui.container.Panel
        XtEditField           matlab.ui.control.NumericEditField
        XtCheckBox            matlab.ui.control.CheckBox
        ZtEditField           matlab.ui.control.NumericEditField
        ZtCheckBox            matlab.ui.control.CheckBox
        XfEditField           matlab.ui.control.NumericEditField
        XfCheckBox            matlab.ui.control.CheckBox
        HeatCheckBox          matlab.ui.control.CheckBox
        HoldCheckBox          matlab.ui.control.CheckBox
        ToZeroCheckBox        matlab.ui.control.CheckBox
        All4CheckBox          matlab.ui.control.CheckBox
        ToZeroButton          matlab.ui.control.StateButton
        HoldButton            matlab.ui.control.StateButton
        HeatButton            matlab.ui.control.StateButton
        ZfPanel               matlab.ui.container.Panel
        ZinEditField          matlab.ui.control.NumericEditField
        ZinLabel              matlab.ui.control.Label
        ZexLabel              matlab.ui.control.Label
        ZexEditField          matlab.ui.control.NumericEditField
        ZfCheckBox            matlab.ui.control.CheckBox
        A33522aPanel          matlab.ui.container.Panel
        All5CheckBox          matlab.ui.control.CheckBox
        Channel1Panel         matlab.ui.container.Panel
        Ch1PrdEditField       matlab.ui.control.NumericEditField
        Ch1PrdCheckBox        matlab.ui.control.CheckBox
        Ch1AmpEditField       matlab.ui.control.NumericEditField
        Ch1AmpCheckBox        matlab.ui.control.CheckBox
        Ch1FreqEditField      matlab.ui.control.NumericEditField
        Ch1FreqCheckBox       matlab.ui.control.CheckBox
        Ch1CheckBox           matlab.ui.control.CheckBox
        Channel2Panel         matlab.ui.container.Panel
        Ch2CheckBox           matlab.ui.control.CheckBox
        Ch2PrdEditField       matlab.ui.control.NumericEditField
        Ch2PrdCheckBox        matlab.ui.control.CheckBox
        Ch2AmpEditField       matlab.ui.control.NumericEditField
        Ch2AmpCheckBox        matlab.ui.control.CheckBox
        Ch2FreqEditField      matlab.ui.control.NumericEditField
        Ch2FreqCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        % Global settings
        
        auxratio=1/5;
        stepAux=0.03;
        delayAux=0.05;
        stepAmp=0.02;
        delayAmp=0.03;
        stepFreq=10;
        delayFreq=0.03;
        steprfFreq=20;
        delayrfFreq=0.1;
        steprfPower=20;
        delayrfPower=0.1;
        
        steprffmrate=20000;
        delayrffmrate=0.1;
        steprffmdev=20000;
        delayrffmdev=0.1;
        stepamrate=2000;
        delayamrate=0.1;
        stepamdep=100;
        delayamdep=0.1;
        
        resetStepAux=0.05;
        resetDelayAux=0.05;
        resetStepFreq=10;
        resetDelayFreq=0.03;
        resetStepAmp=0.01;
        resetDelayAmp=0.03;
        resetsteprfFreq=20;
        resetdelayrfFreq=0.1;
        resetsteprfPower=20;
        resetdelayrfPower=0.1;
        resetPulsePwStep=5000;
        resetPulsePwDelay=0.2;
        resetPulseAmpStep=5;
        resetPulseAmpDelay=0.2;
        resetPulseFreqStep=1;
        resetPulseFreqDelay=0.2;
        resetPulsePeriodStep=1;
        resetPulsePeriodDelay=0.2;
        resetSquareFreqStep=100;
        resetSquareFreqDelay=0.2;
        resetSquareAmpStep=0.005;
        resetSquareAmpDelay=0.3;
        resetSquarePerStep=0.1;
        resetSquarePerDelay=0.3;
        resetsmStep=0.01;
        resetsmDelay=0.05;
        resetisStep=0.02;
        resetisDelay=0.02;
        resetipsStep=8000;
        resetipsDelay=5;
        
        isInit = 0;
        isScanning = 0;
        isPloting = 0;
        
        sweep_conf={};
        %assoc_conf={};
        iterators={};
        lockinRead={};
        labelsRead={};
        readers={};
        labelsForAxes={};
        
        totaltime = 0;
        totalnumber = 0;
        
        
        readParam = [];
        
        
        % instrument
        %         lockin1=SR830({'agilent',7,10});
        %         lockin2=SR830({'agilent',7,11});
        %         lockin3=SR830({'agilent',7,12});
        %         sm=Keithley2400({'agilent',7,24});
        %                 dmm2=Keithley2015({'agilent',7,1});
        %                 dmm=agilent34461({'agilent','TCPIP0::210.45.114.11::inst0::INSTR'});
        %                 dmm=Keithley2015({'agilent',7,6});
        %                 e8257d=E8257D({'agilent',7,19});
        %                 a33522a=agilent33522a({'agilent',7,20});
        %                 agilent81134a=Agilent81134a({'agilent',7,15});
        %                 sim=SIM900({'agilent','GPIB0::2::INSTR'});
        %                 is= IsolatedSource(sim,1);
        %
        %         ips=IPS('gpib',{'agilent',7,25});
        %         ips.open;
        %         pause(1);
        %
        %         ips5 = IPSDemo(2,'192.168.1.194',7020);%ips@tr5
        %         ips5.open;
        %         itek=BiasDac({'COM3'});%serial
        %
        %
        
        
        %         tcpip
        %         itek = BiasDac('192.168.1.141',5000);%itek@tr5_1
        
        %         ips3 = IPSDemo(1,'192.168.1.115',7020);%ips@tr3
        %         lockin1=SR830({'agilent',7,10});%lockin1@tr3_R
        %         lockin2=SR830({'agilent',7,11});%lockin1@tr3_R
        %         %         lockin1 = SR830({'KEYSIGHT', 'GPIB2::10::INSTR'});%lockin1@tr5_R
        %         %         lockin2 = SR830({'KEYSIGHT', 'GPIB2::11::INSTR'});%lockin2@tr5_R
        %         itek = BiasDac('192.168.1.140',5000);%itek@tr3_R
        
        lockin1;
        lockin2;
        itek;
        ips3;
        dmm;
        dmm2;
        a33522a;
        
        %Index State Parameter
        dim = 0;
        dims = 0;
        selectedIndex = zeros(1,3);
        ScanList = {};
        InitList = {};
        
        %Scanning Index
        channel=[];
        from=[];
        step=[];
        to=[];
        delay=[];
        repeat=[];
        filePath = [];
        originalfilePath = [];
        defaultMapPath = [];
        sensI={'2fA','5fA','10fA','20fA','50fA','100fA','200fA','500fA','1pA','2pA','5pA','10pA','20pA','50pA','100pA','200pA','500pA','1nA','2nA','5nA','10nA','20nA','50nA','100nA','200nA','500nA','1uA'};
        sensV={'2nV','5nV','10nV','20nV','50nV','100nV','200nV','500nV','1uV','2uV','5uV','10uV','20uV','50uV','100uV','200uV','500uV','1mV','2mV','5mV','10mV','20mV','50mV','100mV','200mV','500mV','1V'};
        
        
    end
    
    properties (Access = public)
        instrlist = {}; % Description
    end
    
    
    methods (Access = private)


        function SetXIndexState(app,value)
            if value == 1
                set(app.xChannel,'Enable','on');
                set(app.xFrom,'Enable','on');
                set(app.xStep,'Enable','on');
                set(app.xTo,'Enable','on');
                set(app.xDelay,'Enable','on');
                set(app.xRepeat,'Enable','on');
            else
                set(app.xChannel,'Enable','off');
                set(app.xFrom,'Enable','off');
                set(app.xStep,'Enable','off');
                set(app.xTo,'Enable','off');
                set(app.xDelay,'Enable','off');
                set(app.xRepeat,'Enable','off');
            end
        end
        
        function SetYIndexState(app,value)
            if value == 1
                set(app.yChannel,'Enable','on');
                set(app.yFrom,'Enable','on');
                set(app.yStep,'Enable','on');
                set(app.yTo,'Enable','on');
                set(app.yDelay,'Enable','on');
                set(app.yRepeat,'Enable','on');
            else
                set(app.yChannel,'Enable','off');
                set(app.yFrom,'Enable','off');
                set(app.yStep,'Enable','off');
                set(app.yTo,'Enable','off');
                set(app.yDelay,'Enable','off');
                set(app.yRepeat,'Enable','off');
            end
        end
        
        function SetZIndexState(app,value)
            if value == 1
                set(app.zChannel,'Enable','on');
                set(app.zFrom,'Enable','on');
                set(app.zStep,'Enable','on');
                set(app.zTo,'Enable','on');
                set(app.zDelay,'Enable','on');
                set(app.zRepeat,'Enable','on');
            else
                set(app.zChannel,'Enable','off');
                set(app.zFrom,'Enable','off');
                set(app.zStep,'Enable','off');
                set(app.zTo,'Enable','off');
                set(app.zDelay,'Enable','off');
                set(app.zRepeat,'Enable','off');
            end
        end
        
        function [instr,setter,reader,label,resetmode,resetStep,resetDelay]=ChooseInstrForSweep(app,channel,totalwaittime)
            switch channel
                case {1,2,3,4}
                    instr=app.lockin1;
                    label=num2str(channel,'A%d');
                    setter=@(val)(instr.write_aux(channel,val));
                    reader=@()(instr.read_aux(channel));
                    resetmode=1;
                    resetStep=app.resetStepAux;
                    resetDelay=app.resetDelayAux;
                case {5,6,7,8}
                    channel=channel-4;
                    instr=app.lockin2;
                    label=num2str(channel,'B%d');
                    setter=@(val)(instr.write_aux(channel,val));
                    reader=@()(instr.read_aux(channel));
                    resetmode=1;
                    resetStep=app.resetStepAux;
                    resetDelay=app.resetDelayAux;
                case {9,10,11,12}
                    channel=channel-8;
                    instr=app.lockin3;
                    label=num2str(channel,'C%d');
                    setter=@(val)(instr.write_aux(channel,val));
                    reader=@()(instr.read_aux(channel));
                    resetmode=1;
                    resetStep=app.resetStepAux;
                    resetDelay=app.resetDelayAux;
                case 13
                    instr=app.lockin1;
                    label='Freq1';
                    setter=@(val)(instr.write_freq(val));
                    reader=@()(instr.read_freq);
                    resetmode=1;
                    resetStep=app.resetStepFreq;
                    resetDelay=app.resetDelayFreq;
                case 14
                    instr=app.lockin1;
                    label='Amp1';
                    setter=@(val)(instr.write_amp(val));
                    reader=@()(instr.read_amp);
                    resetmode=1;
                    resetStep=app.resetStepAmp;
                    resetDelay=app.resetDelayAmp;
                case 15
                    instr=app.lockin2;
                    label='Freq2';
                    setter=@(val)(instr.write_freq(val));
                    reader=@()(instr.read_freq);
                    resetmode=1;
                    resetStep=app.resetStepFreq;
                    resetDelay=app.resetDelayFreq;
                case 16
                    instr=app.lockin2;
                    label='Amp2';
                    setter=@(val)(instr.write_amp(val));
                    reader=@()(instr.read_amp);
                    resetmode=1;
                    resetStep=app.resetStepAmp;
                    resetDelay=app.resetDelayAmp;
                case 17
                    instr=app.lockin3;
                    label='Freq3';
                    setter=@(val)(instr.write_freq(val));
                    reader=@()(instr.read_freq);
                    resetmode=1;
                    resetStep=app.resetStepFreq;
                    resetDelay=app.resetDelayFreq;
                case 18
                    instr=app.lockin3;
                    label='Amp3';
                    setter=@(val)(instr.write_amp(val));
                    reader=@()(instr.read_amp);
                    resetmode=1;
                    resetStep=app.resetStepAmp;
                    resetDelay=app.resetDelayAmp;
                case 19
                    instr=app.e8257d;
                    label='Rffreq';
                    setter=@(val)(instr.init_freq(val));
                    reader=@()(instr.query_freq);
                    resetmode=1;
                    resetStep=app.resetsteprfFreq;
                    resetDelay=app.resetdelayrfFreq;
                case 20
                    instr=app.e8257d;
                    label='Rfpower';
                    setter=@(val)(instr.init_power(val));
                    reader=@()(instr.query_power);
                    resetmode=1;
                    resetStep=app.resetsteprfPower;
                    resetDelay=app.resetdelayrfPower;
                case 21
                    instr=app.agilent81134a;
                    label='Pw1';
                    setter=@(val)(instr.init_width(1,val));
                    reader=@()(instr.query_width(1));
                    resetmode=1;
                    resetStep=app.resetPulsePwStep;
                    resetDelay=app.resetPulsePwDelay;
                case 22
                    instr=app.agilent81134a;
                    label='Amp1';
                    setter=@(val)(instr.init_amp(1,val));
                    reader=@()(instr.query_amp(1));
                    resetmode=1;
                    resetStep=app.resetPulseAmpStep;
                    resetDelay=app.resetPulseAmpDelay;
                case 23
                    instr=app.agilent81134a;
                    label='Pw2';
                    setter=@(val)(instr.init_width(2,val));
                    reader=@()(instr.query_width(2));
                    resetmode=1;
                    resetStep=app.resetPulsePwStep;
                    resetDelay=app.resetPulsePwDelay;
                case 24
                    instr=app.agilent81134a;
                    label='Amp2';
                    setter=@(val)(instr.init_amp(2,val));
                    reader=@()(instr.query_amp(2));
                    resetmode=1;
                    resetStep=app.resetPulseAmpStep;
                    resetDelay=app.resetPulseAmpDelay;
                case 25
                    instr=app.agilent81134a;
                    label='PulseFreq';
                    setter=@(val)(instr.init_freq(val));
                    reader=@()(instr.query_freq);
                    resetmode=1;
                    resetStep=app.resetPulseFreqStep;
                    resetDelay=app.resetPulseFreqDelay;
                case 26
                    instr=app.agilent81134a;
                    label='PulsePeriod';
                    setter=@(val)(instr.init_period(val));
                    reader=@()(instr.query_period);
                    resetmode=1;
                    resetStep=app.resetPulsePeriodStep;
                    resetDelay=app.resetPulsePeriodDelay;
                case 27
                    instr=app.a33522a;
                    label='SquareFreq';
                    setter=@(val)(instr.set_freq(1,val));
                    reader=@()(instr.query_freq(1));
                    resetmode=1;
                    resetStep=app.resetSquareFreqStep;
                    resetDelay=app.resetSquareFreqDelay;
                case 28
                    instr=app.a33522a;
                    label='SquareAmp';
                    setter=@(val)(instr.set_amp(1,val));
                    reader=@()(instr.query_amp(1));
                    resetmode=1;
                    resetStep=app.resetSquareAmpStep;
                    resetDelay=app.resetSquareAmpDelay;
                case 29
                    instr=app.a33522a;
                    label='SquarePeriod';
                    setter=@(val)(instr.set_period(1,val));
                    reader=@()(instr.query_period(1));
                    resetmode=1;
                    resetStep=app.resetSquarePerStep;
                    resetDelay=app.resetSquarePerDelay;
                case 30
                    instr=sm;
                    label='BG';
                    setter=@(val)(instr.set_volt(val));
                    reader=@()(instr.query_volt);
                    resetmode=1;
                    resetStep=app.resetsmStep;
                    resetDelay=app.resetsmDelay;
                case 31
                    instr=sm;
                    label='I_source';
                    setter=@(val)(instr.set_curr(val));
                    reader=@()(instr.query_curr);
                    resetmode=1;
                    resetStep=0.05;
                    resetDelay=0.02;
                case 111
                    instr=app.sim;
                    label='Bias_SD';
                    setter=@(val)(app.is.set_volt(val));
                    reader=@()(app.is.read_volt);
                    resetmode=1;
                    resetStep=app.resetisStep;
                    resetDelay=app.resetisDelay;
                case 120
                    instr=ips;
                    label='B';
                    if totalwaittime>3600
                        setter=@(val)(instr.set_field(val,1));
                    else
                        setter=@(val)(instr.set_field(val,0));
                    end
                    reader=@()(instr.read_internal_field);
                    resetmode=0;
                    resetStep=app.resetipsStep;
                    resetDelay=app.resetipsDelay;
                    
                case 121%ips@tr3 direction x
                    instr=app.ips3;
                    label='B';
                    setter=@(val)(instr.set_field_x(val));
                    reader=@()(instr.read_persistent_field_x);
                    resetmode=0;
                    resetStep=app.resetipsStep;
                    resetDelay=app.resetipsDelay;
                    
                case 122%ips@tr3 direction z
                    instr=app.ips3;
                    label='B';
                    setter=@(val)(instr.set_field_z(val));
                    reader=@()(instr.read_persistent_field_z);
                    resetmode=0;
                    resetStep=app.resetipsStep;
                    resetDelay=app.resetipsDelay;
                    
                case 123%ips@tr3 direction z with Switch Heater On
                    instr=app.ips3;
                    label='B';
                    setter=@(val)(instr.set_field_z_swhto(val));
                    reader=@()(instr.read_persistent_field_z);
                    resetmode=0;
                    resetStep=app.resetipsStep;
                    resetDelay=app.resetipsDelay;
                    
                case 124%ips@tr5 direction x
                    instr=ips5;
                    label='B';
                    setter=@(val)(instr.set_field_x(val));
                    reader=@()(instr.read_persistent_field_x);
                    resetmode=0;
                    resetStep=app.resetipsStep;
                    resetDelay=app.resetipsDelay;
                    
                case 125%ips@tr5 direction y
                    instr=ips5;
                    label='B';
                    setter=@(val)(instr.set_field_y(val));
                    reader=@()(instr.read_persistent_field_y);
                    resetmode=0;
                    resetStep=app.resetipsStep;
                    resetDelay=app.resetipsDelay;
                    
                case 126%ips@tr5 direction z
                    instr=ips5;
                    label='B';
                    setter=@(val)(instr.set_field_z(val));
                    reader=@()(instr.read_persistent_field_z);
                    resetmode=0;
                    resetStep=app.resetipsStep;
                    resetDelay=app.resetipsDelay;
                    
                case {201,202,203,204,205,206,207,208,209,210,211,212,213,214,215,216}
                    instr=app.itek;
                    %                     label=['I',char(65+floor((channel-201)/4)),num2str(channel-200-floor((channel-201)/4)*4)];
                    
                    channel=channel-200;
                    label = sprintf('U%d',channel);
                    setter=@(val)(instr.set_volt(channel,val));
                    reader=@()(instr.query_volt(channel));
                    resetmode=1;
                    resetStep=app.resetStepAux;
                    resetDelay=app.resetDelayAux;
                otherwise
                    error('unsurported sweep channel');
            end
        end
        
        function [instr,reader,label]=ChooseInstrForRead(app,type)
            switch type
                case {1,2,3,4}
                    instr=app.lockin1;
                    label='Current1';
                    switch type
                        case 1
                            label=[label '(X)'];
                        case 2
                            label=[label '(Y)'];
                        case 3
                            label=[label '(R)'];
                        case 4
                            label=[label '(Theta)'];
                    end
                    reader=@()(instr.read_current(type));
                case {5,6,7,8}
                    instr=app.lockin2;
                    label='Current2';
                    type=type-4;
                    switch type
                        case 1
                            label=[label '(X)'];
                        case 2
                            label=[label '(Y)'];
                        case 3
                            label=[label '(R)'];
                        case 4
                            label=[label '(Theta)'];
                    end
                    reader=@()(instr.read_current(type));
                case {9,10,11,12}
                    instr=lockin3;
                    label='Current3';
                    type=type-8;
                    switch type
                        case 1
                            label=[label '(X)'];
                        case 2
                            label=[label '(Y)'];
                        case 3
                            label=[label '(R)'];
                        case 4
                            label=[label '(Theta)'];
                    end
                    reader=@()(instr.read_current(type));
                case 13
                    instr=app.dmm;
                    label='Current4';
                    reader=@()(instr.read); %for 2015P ".fetch"
                case 14
                    instr=app.dmm2;
                    label='Current5';
                    reader=@()(instr.read);
                otherwise
                    error('unsurported read type');
            end
        end
        
        function status=dispatch_lockin(app)
            app.sweep_conf={};
            app.iterators={};
            app.lockinRead={};
            app.labelsRead={};
            app.readers={};
            app.labelsForAxes={};
            
            app.totaltime = 0;
            app.totalnumber = 1;
            app.dims = 0;
            
            status=1;
            for i=app.dim:-1:1
                [h,s,r,l,rm,rs,rd]=ChooseInstrForSweep(app,app.channel(i),app.totaltime);
                app.sweep_conf{i}.instr=h;
                app.sweep_conf{i}.setter=s;
                app.sweep_conf{i}.reader=r;
                app.sweep_conf{i}.label=l;
                app.sweep_conf{i}.resetmode=rm;
                app.sweep_conf{i}.resetStep=rs;
                app.sweep_conf{i}.resetDelay=rd;
                app.sweep_conf{i}.channel=app.channel(i);
                app.sweep_conf{i}.from=app.from(i);
                app.sweep_conf{i}.step=app.step(i);
                app.sweep_conf{i}.to=app.to(i);
                app.sweep_conf{i}.delay=app.delay(i);
                app.sweep_conf{i}.repeat=app.repeat(i);
                if ~check_value(app.sweep_conf{i}.channel,app.sweep_conf{i}.from,'from')||~check_value(app.sweep_conf{i}.channel,app.sweep_conf{i}.to,'to')
                    status=0;
                    return;
                end
                app.iterators{i}=Iterator02(app.sweep_conf{i}.setter,app.sweep_conf{i}.from,app.sweep_conf{i}.step,app.sweep_conf{i}.to,app.sweep_conf{i}.delay,app.sweep_conf{i}.label,app.sweep_conf{i}.repeat,app.sweep_conf{i}.resetmode,app.sweep_conf{i}.resetStep,app.sweep_conf{i}.resetDelay);
                if i==app.dim
                    app.totaltime = abs(app.sweep_conf{i}.from-app.sweep_conf{i}.to)*(app.sweep_conf{i}.delay/abs(app.sweep_conf{i}.step)+app.sweep_conf{i}.resetDelay/app.sweep_conf{i}.resetStep)*app.sweep_conf{i}.repeat;
                else
                    app.totaltime=abs(app.sweep_conf{i}.from-app.sweep_conf{i}.to)*app.sweep_conf{i}.repeat*((app.sweep_conf{i}.delay+app.totaltime)/abs(app.sweep_conf{i}.step)+app.sweep_conf{i}.resetDelay/app.sweep_conf{i}.resetStep);
                    app.totalnumber = (floor(abs(app.sweep_conf{i}.from-app.sweep_conf{i}.to)/abs(app.sweep_conf{i}.step))+1)*app.totalnumber;
                    
                end
                if app.sweep_conf{i}.resetmode==1
                    app.totaltime=abs(app.sweep_conf{i}.from-app.sweep_conf{i}.to)/abs(app.sweep_conf{i}.resetStep)*app.sweep_conf{i}.resetDelay+app.totaltime;
                end
                
                app.sweep_conf{i}.assoc_conf={};
                app.sweep_conf{i}.hasAssoc=0;
                %                 if selectedAssocParams{i}.enable
                %                     app.sweep_conf{i}.hasAssoc=length(selectedAssocParams{i}.channelParam);
                %                     for n=1:length(selectedAssocParams{i}.channelParam)
                %                         p=selectedAssocParams{i}.channelParam{n};
                %                         app.sweep_conf{i}.assoc_conf{n}.channel=p(1);
                %                         app.sweep_conf{i}.assoc_conf{n}.from=p(2);
                %                         app.sweep_conf{i}.assoc_conf{n}.slope=p(3);
                %                         [h,s,r,l,rm,rs,rd]=ChooseInstrForSweep(app,p(1));
                %                         app.sweep_conf{i}.assoc_conf{n}.instr=h;
                %                         app.sweep_conf{i}.assoc_conf{n}.setter=s;
                %                         app.sweep_conf{i}.assoc_conf{n}.reader=r;
                %                         app.sweep_conf{i}.assoc_conf{n}.label=l;
                %                         app.sweep_conf{i}.assoc_conf{n}.resetmode=rm;
                %                         app.sweep_conf{i}.assoc_conf{n}.resetStep=rs;
                %                         app.sweep_conf{i}.assoc_conf{n}.resetDelay=rd;
                %                         if ~check_value(app.sweep_conf{i}.assoc_conf{n}.channel,app.sweep_conf{i}.assoc_conf{n}.from,'from')
                %                             status=0;
                %                             return;
                %                         end
                %                         app.iterators{i}.addAssoc(app.sweep_conf{i}.assoc_conf{n}.setter,app.sweep_conf{i}.assoc_conf{n}.from,app.sweep_conf{i}.assoc_conf{n}.slope,app.sweep_conf{i}.assoc_conf{n}.label);
                %                     end
                %                 end
                app.dims=app.dims+app.sweep_conf{i}.hasAssoc+1;
                
            end
            for i=1:length(app.readParam)
                [h,r,l]=ChooseInstrForRead(app,app.readParam(i));
                app.lockinRead{i}=h;
                app.labelsRead{i}=l;
                app.readers{i}=r;
            end
            app.labelsForAxes{1}=app.sweep_conf{app.dim}.label;
            
            app.labelsForAxes(2:length(app.labelsRead)+1)=app.labelsRead;
            %             app.totalnumber = app.totalnumber + 1;
        end


        function init_channel(~,reader,setter,value,step,delay)
            current_value=reader();
            abs_step=abs(step);
            if current_value>value
                while current_value-value>abs_step
                    current_value=current_value-abs_step;
                    setter(current_value);
                    pause(delay);
                end
            else
                while value-current_value>abs_step
                    current_value=current_value+abs_step;
                    setter(current_value);
                    pause(delay);
                end
            end
            setter(value);
            pause(delay);
        end


        %         function setColor(~,plotter,colors)
        %             plotter.colors=colors;
        %             m=min([length(plotter.colors),plotter.lineNum]);
        %             if ishandle(plotter.hLines)
        %                 for i=1:m
        %                     set(plotter.hLines(i),'Color',plotter.colors{i});
        %                 end
        %             end
        %         end
        
        %         function setLabel(app,labels)
        %             m = length(labels);
        %             if m ~= 0
        %                 set(app.UIAxes.XLabel,labels{1});
        %                 for i = 2:m
        %                     set(app.UIAxes.YLabel,labels{i});
        %                 end
        %             end
        %         end
        
        
        function init_instr(app,reader,setter,value,step,delay,label)
            current_value=reader();
            abs_step=abs(step);
            hlabel = eval(label);
            if current_value>value
                while current_value-value>abs_step
                    if ~app.isInit
                        return;
                    end
                    current_value=current_value-abs_step;
                    setter(current_value);
                    set(hlabel,'Value',current_value);
                    pause(delay);
                end
            else
                while value-current_value>abs_step
                    if ~app.isInit
                        return;
                    end
                    current_value=current_value+abs_step;
                    setter(current_value);
                    set(hlabel,'Value',current_value);
                    pause(delay);
                end
            end
            setter(value);
            set(hlabel,'Value',value);
        end
        
        
        function write_parameter(app)
            instrfile = fullfile(app.filePath,'instrument_param.txt');
            fd = fopen(instrfile,'w');
            if fd == -1
                fprintf(1,'Can not open %s\r\n',instrfile);
            else
                flag = 0;
                for j = 1:length(app.InstrDropDown.Items)
                    switch app.InstrDropDown.Items{j}
                        case 'lockin 1'
                            if app.lockin1.isopened
                                flag = 1;
                            else
                                flag = 0;
                                app.lockin1.open;
                            end
                            for i = 1:4
                                fprintf(fd,'A%g\t%g\r\n',i,app.lockin1.read_aux(i));
                            end
                            fprintf(fd,'AMP1\t%g\r\n',app.lockin1.read_amp());
                            fprintf(fd,'FREQ1\t%g\r\n',app.lockin1.read_freq());
                            if ~flag
                                app.lockin1.close;
                            end
                            
                        case 'lockin 2'
                            if app.lockin2.isopened
                                flag = 1;
                            else
                                flag = 0;
                                app.lockin2.open;
                            end
                            for i = 1:4
                                fprintf(fd,'B%g\t%g\r\n',i,app.lockin2.read_aux(i));
                            end
                            fprintf(fd,'AMP2\t%g\r\n',app.lockin2.read_amp());
                            fprintf(fd,'FREQ2\t%g\r\n',app.lockin2.read_freq());
                            if ~flag
                                app.lockin2.close;
                            end
                        case 'lockin 3'
                            if app.lockin3.isopened
                                flag = 1;
                            else
                                flag = 0;
                                app.lockin3.open;
                            end
                            for i = 1:4
                                fprintf(fd,'C%g\t%g\r\n',i,app.lockin3.read_aux(i));
                            end
                            fprintf(fd,'AMP3\t%g\r\n',app.lockin3.read_amp());
                            fprintf(fd,'FREQ3\t%g',app.lockin3.read_freq());
                            if ~flag
                                app.lockin3.close;
                            end
                    end
                end
                fclose(fd);
                
                
            end


            param_prevfile=fullfile(app.filePath,'param_prev.txt');
            fd = fopen(param_prevfile,'w');
            if fd == -1
                fprintf(1,'Can not open %s\r\n',param_prevfile);
            else
                
                title = sprintf('\t');
                content = '';
                for i = 1:app.dim
                    title = [title,app.iterators{i}.get_short_description];
                    content = [content,app.iterators{i}.get_long_description];
                    
                    if i < app.dim
                        title = [title,'-'];
                        content = [content,'  '];
                    else
                        title = [title,':'];
                        content = [content,sprintf('\r\n')];
                    end
                end
                index = zeros(1,length(app.labelsRead));
                for i = 1:length(app.labelsRead)
                    tokens = regexp(app.labelsRead{i},'Current(\d+)','tokens');
                    index(i) = str2num(tokens{1}{1});
                    tmp = regexprep(app.labelsRead{i},'Current(\d+)','@Current$1{Current$1}');
                    title = [title,tmp];
                    if i < length(app.labelsRead)
                        title = [title,'-'];
                    end
                end
                index = sort(unique(index));
                for i = 1:length(index)
                    content = [content,sprintf('@Current%d{Current%d}(@Bias%d  @Modulation%d  @Source%d  @Measure%d)',index(i),index(i),index(i),index(i),index(i),index(i))];
                    if i < length(index)
                        content = [content,'  '];
                    else
                        content = [content,sprintf('\r\n')];
                    end
                end
                fprintf(fd,'%s\r\n%s',title,content);
                
                
                flag = 0;
                
                for i = 1:length(app.InstrDropDown.Items)
                    switch app.InstrDropDown.Items{i}
                        case 'lockin 1'
                            if app.lockin1.isopened
                                flag=1;
                            else
                                flag=0;
                                app.lockin1.open();
                            end
                            for j = 1:4
                                fprintf(fd,'A%g(%g)',j,app.lockin1.read_aux(j));
                            end
                            if ~flag
                                app.lockin1.close();
                            end
                        case 'lockin 2'
                            if app.lockin2.isopened
                                flag=1;
                            else
                                flag=0;
                                app.lockin2.open;
                            end
                            for j=1:4
                                fprintf(fd,'B%g(%g)',j,app.lockin2.read_aux(j));
                            end
                            if ~flag
                                app.lockin2.close;
                            end
                        case 'lockin 3'
                            if app.lockin3.isopened
                                flag=1;
                            else
                                flag=0;
                                app.lockin3.open;
                            end
                            for j=1:4
                                fprintf(fd,'C%g(%g)',j,app.lockin3.read_aux(j));
                            end
                            if ~flag
                                app.lockin3.close;
                            end
                        case 'itek'
                            if app.itek.isopened
                                flag = 1;
                            else
                                flag = 0;
                                app.itek.open;
                            end
                            for j = 1:8
                                %                                 label = sprintf('U%d',j);
                                label = ['I',char(65+floor((j-1)/4)),num2str(j-floor((j-1)/4)*4)];
                                fprintf(fd,'%s(%g)',label,app.itek.query_volt(j));
                            end
                            fprintf(fd,'\r\n');
                            for j = 9:16
                                %                                 label = sprintf('U%d',j);
                                label = ['I',char(65+floor((j-1)/4)),num2str(j-floor((j-1)/4)*4)];
                                fprintf(fd,'%s(%g)',label,app.itek.query_volt(j));
                            end
                            if ~flag
                                app.itek.close;
                            end
                    end
                    
                    %                     fprintf('\r\n');
                    fprintf(fd,'\r\n');
                    %                 if exist('e8257d','var')
                    %                     if e8257d.isopened
                    %                         flag=1;
                    %                     else
                    %                         flag=0;
                    %                         e8257d.open;
                    %                     end
                    %                     if e8257d.query_output_state
                    %                         fprintf(fd,'Rffreq(%gGHz)Rfpower(%gdBm)',e8257d.query_freq,e8257d.query_power);
                    %                     end
                    %                     if ~flag
                    %                         e8257d.close;
                    %                     end
                    %                 end
                    %                 if exist('agilent81134a','var')
                    %                     if agilent81134a.isopened
                    %                         flag=1;
                    %                     else
                    %                         flag=0;
                    %                         agilent81134a.open;
                    %                     end
                    %                     if agilent81134a.query_dut_state
                    %                         fprintf(fd,'PulseFreq(%gMHz)',agilent81134a.query_freq);
                    %                         if agilent81134a.query_output_state(1)==1
                    %                             fprintf(fd,'Pw1(%gps)Amp1(%gmv)',agilent81134a.query_width(1),agilent81134a.query_amp(1));
                    %                         end
                    %                         if agilent81134a.query_output_state(2)==1
                    %                             fprintf(fd,'Pw2(%gps)Amp2(%gmv)',agilent81134a.query_width(2),agilent81134a.query_amp(2));
                    %                         end
                    %                     end
                    %                     if ~flag
                    %                         agilent81134a.close;
                    %                     end
                    %                 end
                    %                 if exist('sm','var')
                    %                     if sm.isopened
                    %                         flag=1;
                    %                     else
                    %                         flag=0;
                    %                         sm.open;
                    %                     end
                    %                     fprintf(fd,'BG(%g)',sm.query_volt);
                    %
                    %                     if ~flag
                    %                         sm.close;
                    %                     end
                    %                 end
                    %                 if exist('is','var')
                    %                     if sim.isopened
                    %                         flag=1;
                    %                     else
                    %                         flag=0;
                    %                         sim.open;
                    %                     end
                    %                     fprintf(fd,'Bias(%g)',is.read_volt);
                    %
                    %                     if ~flag
                    %                         sim.close;
                    %                     end
                    %                 end
                end
                fprintf(fd,'\r\n%s',app.filePath);
                fclose(fd);
                if exist(instrfile,'file')&&exist(param_prevfile,'file')
                    if exist(app.defaultMapPath,'file')
                        copyfile(app.defaultMapPath,app.filePath);
                        process_parameter_txt03(param_prevfile,instrfile,app.defaultMapPath);
                    end
                end
            end
        end
        
        function Scanner(app,filewriter)%iterators readers are cell array
            n=length(app.iterators);
            assocFlag=zeros(1,n);
            sValIndex=zeros(1,n);
            numofsVal=0;
            
            colorlist = {'black','red','blue','cyan','m'};
            curnumber = 0;
            for i=1:n
                sValIndex(i)=numofsVal+1;
                assocFlag(i)=app.iterators{i}.hasAssoc+1;
                numofsVal=numofsVal+assocFlag(i);
            end
            sVal=cell(1,numofsVal);
            m=length(app.readers);
            rVal=cell(1,m);


            close all;
            
            f = cell(1,m);
            for i = 1:m
                f{i} = figure;
                f{i}.MenuBar = 'none';
                f{i}.ToolBar = 'none';
                f{i}.Position = [800*i-600 400 800 600];
                f{i}.Color = [1 1 1];
                hold on;
                grid on;
                xlabel(app.sweep_conf{1}.label)
                ylabel(app.labelsRead{i});
                
                ploter(i) = animatedline;
                ploter(i).Color = colorlist{mod(i,5)};
            end


            %             if ~exist('ploter2d','var')
            %                 ploter2d=[];
            %             end
            
            
            %次数超过一次且时间超过10s，显示进度条
            if app.totalnumber > 1 && app.totaltime > 10
                tips = sprintf('Scanning ...%d figures remaining, please wait %g seconds',app.totalnumber,app.totaltime);%显示等待进度条
                hwait = waitbar(0,tips,'Name','Scanning');
                pause(1);
                curnumber = curnumber + 1;
                ss = ceil(app.totaltime - app.totaltime * (curnumber - 1) / app.totalnumber);
                hms = fix(mod(ss, [0, 3600, 60])./[3600, 60, 1]);
                if hms(1)
                    tips = sprintf('Scanning %d of %d figures … %d hours %d minutes remaining',curnumber,app.totalnumber,hms(1),hms(2));
                elseif hms(2)
                    tips = sprintf('Scanning %d of %d figures … %d minutes %d seconds remaining',curnumber,app.totalnumber,hms(2),hms(3));
                else
                    tips = sprintf('Scanning %d of %d figures … %d seconds remaining',curnumber,app.totalnumber,hms(3));
                end
                waitbar(curnumber/app.totalnumber,hwait,tips);
            end


            filewriter.reset(0);
            cur=1;
            while 1
                if ~app.isScanning
                    if app.totalnumber > 1
                        delete(hwait);
                    end
                    return;
                end
                val=app.iterators{cur}.next;
                if ~isempty(val)
                    startIndex=sValIndex(cur);
                    for i=1:assocFlag(cur)
                        sVal{startIndex+i-1}=val(i);
                    end
                    
                    if cur==n
                        for i=1:m
                            func=app.readers{i};
                            rVal{i}=func();
                            addpoints(ploter(i),sVal{startIndex},rVal{i});
                        end
                        %                         if ~isempty(ploter2d)
                        %                             ploter2d.update([sVal{startIndex} rVal{:}]);
                        %                         end
                        
                        drawnow;
                        
                        filewriter.write(sVal,rVal);
                    else
                        cur=cur+1;
                    end
                else
                    if cur==1
                        if ~app.isScanning
                            if app.totalnumber > 1
                                delete(hwait);
                            end
                            return;
                        end
                        filewriter.reset(cur-1);
                        break;
                    else
                        if ~app.isScanning
                            if app.totalnumber > 1
                                delete(hwait);
                            end
                            return;
                        end
                        app.iterators{cur}.reset;
                        if app.iterators{cur-1}.nextFresh
                            filewriter.reset(cur-1,1);
                        else
                            filewriter.reset(cur-1,2);
                        end
                        if cur==n
                            %                             if~isempty(ploter2d)
                            %                                 ploter2d.clear;
                            %                             end
                            
                            %更新进度条
                            if curnumber < app.totalnumber
                                curnumber = curnumber + 1;
                                ss = ceil(app.totaltime - app.totaltime * (curnumber - 1) / app.totalnumber);
                                hms = fix(mod(ss, [0, 3600, 60])./[3600, 60, 1]);
                                if hms(1)
                                    tips = sprintf('Scanning %d of %d figures … %d hours %d minutes %d seconds remaining',curnumber,app.totalnumber,hms(1),hms(2),hms(3));
                                elseif hms(2)
                                    tips = sprintf('Scanning %d of %d figures … %d minutes %d seconds remaining',curnumber,app.totalnumber,hms(2),hms(3));
                                else
                                    tips = sprintf('Scanning %d of %d figures … %d seconds remaining',curnumber,app.totalnumber,hms(3));
                                end
                                waitbar(curnumber/app.totalnumber,hwait,tips);
                            end
                            
                            if ~app.HoldStateButton.Value
                                for i = 1:m
                                    clearpoints(ploter(i));
                                end
                            end
                            drawnow;
                        end
                        cur=cur-1;
                    end
                end
            end
            
            if app.totalnumber > 1
                delete(hwait);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.instrlist = {'lockin 1','lockin 2','itek','dmm','a33522a','ips3'};
            tips = 'Select instrments in use:';
            title = 'Instruments selection';
            
            [indx,tf]= listdlg('ListString', app.instrlist,'PromptString',tips,'Name',title,'ListSize',[300,100],'InitialValue',[1 2 3 4 5]);
            
            if tf
                app.instrlist = {'-'};
                
                for i = 1:length(indx)
                    % instrument
                    switch indx(i)
                        case 1
%                             app.lockin1 = SR830({'GPIB2::10::INSTR'});%lockin1@tr3_R
                            app.instrlist = [app.instrlist, 'lockin 1'];
                        case 2
%                             app.lockin2 = SR830({'GPIB2::11::INSTR'});%lockin1@tr3_R
                            app.instrlist = [app.instrlist, 'lockin 2'];
                        case 3
%                             app.itek = BiasDac('192.168.1.140',5000);%itek@tr3_R
                            app.instrlist = [app.instrlist, 'itek'];
                        case 4
%                             app.dmm = Keithley2015({'GPIB1::1::INSTR'});%dmm@tr3
                            app.instrlist = [app.instrlist, 'dmm'];
                        case 5
%                             app.a33522a=agilent33522a({'TCPIP0::192.168.1.116::inst0::INSTR'});%awg a33522a @tr3
                            app.instrlist = [app.instrlist, 'a33522a'];
                        case 6
%                             app.ips3 = IPSDemo(1,'192.168.1.115',7020);%ips@tr3
                            app.instrlist = [app.instrlist, 'ips3'];
                            
                            %                             lockin1=SR830({'agilent',7,10});
                            %                             lockin2=SR830({'agilent',7,11});
                            %                             lockin3=SR830({'agilent',7,12});
                            %                             sm=Keithley2400({'agilent',7,24});
                            %                             dmm2=Keithley2015({'agilent',7,1});
                            %                             dmm=agilent34461({'agilent','TCPIP0::210.45.114.11::inst0::INSTR'});
                            %                             dmm=Keithley2015({'agilent',7,6});
                            %                             e8257d=E8257D({'agilent',7,19});
                            %
                            %                             agilent81134a=Agilent81134a({'agilent',7,15});
                            %                             sim=SIM900({'agilent','GPIB0::2::INSTR'});
                            %                             is= IsolatedSource(sim,1);
                            %
                            %                             ips=IPS('gpib',{'agilent',7,25});
                            %                             ips.open;
                            %                             pause(1);
                            %
                            %                             ips5 = IPSDemo(2,'192.168.1.194',7020);%ips@tr5
                            %                             ips5.open;
                            %
                            %
                            %
                            %
                            %                             tcpip
                            %                             itek = BiasDac('192.168.1.141',5000);%itek@tr5_1
                    end
                    app.ScanList = intersect(app.instrlist,{'lockin 1','lockin 2','dmm'});
                    
                    if isempty(app.ScanList)
                        set(app.ParameterPanel,'Visible','Off');
                        set(app.ReadPanel,'Visible','Off');
                        set(app.Button,'Visible','Off');
                        set(app.DisableLabel,'Visible','On');
                    end
                    
                    app.InitList = intersect(app.instrlist,{'-','lockin 1','lockin 2','itek','ips3','a33522a'});
                    
                    app.InstrDropDown.Items = app.InitList;
                end
            else
                closereq;
                return;
            end
            
            app.dim = 0;
            app.selectedIndex = zeros(1,3);
        end

        % Value changed function: XCheckBox
        function XCheckBoxValueChanged(app, event)
            SetXIndexState(app,app.XCheckBox.Value);
        end

        % Value changed function: YCheckBox
        function YCheckBoxValueChanged(app, event)
            SetYIndexState(app,app.YCheckBox.Value);
        end

        % Value changed function: ZCheckBox
        function ZCheckBoxValueChanged(app, event)
            SetZIndexState(app,app.ZCheckBox.Value);
        end

        % Callback function
        function StartButtonPushed(app, event)
            app.dim = 0;
            
            %Check Index dimension & get scanning parameters
            if app.XCheckBox.Value
                if strcmp(app.xChannel.Text,':')
                    errordlg('Check xChannel','Parameter Check','modal');
                    return;
                else
                    app.dim = app.dim + 1;
                    app.selectedIndex(1) = 1;
                    app.channel(app.dim) = str2num(app.xChannel.Text);
                    app.from(app.dim) = app.xFrom.Value;
                    app.step(app.dim) = app.xStep.Value;
                    app.to(app.dim) = app.xTo.Value;
                    app.delay(app.dim) = app.xDelay.Value;
                    app.repeat(app.dim) = app.xRepeat.Value;
                end
            end
            
            if app.YCheckBox.Value
                if strcmp(app.yChannel.Text,':')
                    errordlg('Check yChannel','Parameter Check','modal');
                    return;
                else
                    app.dim = app.dim + 1;
                    app.selectedIndex(2) = 1;
                    app.channel(app.dim) = str2num(app.yChannel.Text);
                    app.from(app.dim) = app.yFrom.Value;
                    app.step(app.dim) = app.yStep.Value;
                    app.to(app.dim) = app.yTo.Value;
                    app.delay(app.dim) = app.yDelay.Value;
                    app.repeat(app.dim) = app.yRepeat.Value;
                end
            end
            
            if app.ZCheckBox.Value
                if strcmp(app.zChannel.Text,':')
                    errordlg('Check zChannel','Parameter Check','modal');
                    return;
                else
                    app.dim = app.dim + 1;
                    app.selectedIndex(3) = 1;
                    app.channel(app.dim) = str2num(app.zChannel.Text);
                    app.from(app.dim) = app.zFrom.Value;
                    app.step(app.dim) = app.zStep.Value;
                    app.to(app.dim) = app.zTo.Value;
                    app.delay(app.dim) = app.zDelay.Value;
                    app.repeat(app.dim) = app.zRepeat.Value;
                end
            end
            
            if strcmp(app.Read.Text,':')
                errordlg('Check Read parameter','Parameter Check','modal');
                return;
            else
                app.readParam = str2num(app.Read.Text);
            end
            
            
            if ~app.dim
                errordlg('no Indexs selected');
                return;
            end
            
            if ~dispatch_lockin(app)
                return;
            end
            
            %UI figure off
            set(app.StartButton,'Enable','off');
            set(app.StopButton,'Enable','on');
            set(app.HoldStateButton,'Enable','on');
            set(app.HoldStateButton,'Value',1);
            %             set(app.ReadButton,'Enable','off');
            %             set(app.InitButton,'Enable','off');
            
            set(app.XCheckBox,'Enable','off');
            SetXIndexState(app,0);
            
            set(app.YCheckBox,'Enable','off');
            SetYIndexState(app,0);
            
            set(app.ZCheckBox,'Enable','off');
            SetZIndexState(app,0);
            
            set(app.Read,'Enable','off');
            set(app.FilePath,'Enable','off');


            %create data files
            app.filePath = app.FilePath.Value;
            if isempty(app.filePath)
                errordlg('No File Name');
                return;
            end
            if ~exist(app.filePath,'dir')
                mkdir(app.filePath);
            end
            app.originalfilePath=app.filePath;
            datestring=datestr(now,'mmmm-dd-yyyy_HH-MM-SS');
            app.filePath=fullfile(app.filePath,datestring);
            if ~exist(app.filePath,'dir')
                mkdir(app.filePath);
            end
            
            defaultPath='E:\MATLAB code\semiconductor group\revised08';
            app.defaultMapPath=fullfile(defaultPath,'map.txt');
            
            write_parameter(app);
            
            
            dimr=length(app.readers);
            fw=FileWriter03(app.dim,app.dims+dimr,app.filePath);
            
            for i=1:app.dim
                app.sweep_conf{i}.instr.open;
                if app.sweep_conf{i}.resetmode==1
                    init_channel(app,app.sweep_conf{i}.reader,app.sweep_conf{i}.setter,app.sweep_conf{i}.from,app.sweep_conf{i}.resetStep,app.sweep_conf{i}.resetDelay);
                end
                for j=1:app.sweep_conf{i}.hasAssoc
                    app.sweep_conf{i}.assoc_conf{j}.instr.open;
                    if app.sweep_conf{i}.assoc_conf{j}.resetmode==1
                        
                        init_channel(app,app.sweep_conf{i}.assoc_conf{j}.reader,app.sweep_conf{i}.assoc_conf{j}.setter,app.sweep_conf{i}.assoc_conf{j}.from,app.sweep_conf{i}.assoc_conf{j}.resetStep,app.sweep_conf{i}.assoc_conf{j}.resetDelay);
                    end
                end
            end
            for i=1:length(app.lockinRead)
                app.lockinRead{i}.open;
            end
            app.isScanning = 1;
            Scanner(app, fw);
            app.isScanning = 0;
            
            %             for i=1:app.dim
            %                 if app.sweep_conf{i}.instr~=ips
            %                     app.sweep_conf{i}.instr.close;
            %                 end
            %                 for j=1:app.sweep_conf{i}.hasAssoc
            %                     if app.sweep_conf{i}.assoc_conf{j}.instr~=ips
            %                         app.sweep_conf{i}.assoc_conf{j}.instr.close;
            %                     end
            %                 end
            %             end
            for i=1:length(app.readers)
                app.lockinRead{i}.close;
            end
            %           if hasAssoc
            %              assoc_conf.instr.close;
            %           end
            delete(fw);
            
            
            %UI figure on
            set(app.StartButton,'Enable','on');
            set(app.StopButton,'Enable','off');
            set(app.HoldStateButton,'Enable','off');
            set(app.HoldStateButton,'Value',0);
            %             set(app.ReadButton,'Enable','on');
            %             set(app.InitButton,'Enable','on');
            
            set(app.XCheckBox,'Enable','on');
            SetXIndexState(app,app.XCheckBox.Value);
            
            set(app.YCheckBox,'Enable','on');
            SetYIndexState(app,app.YCheckBox.Value);
            
            set(app.ZCheckBox,'Enable','on');
            SetZIndexState(app,app.ZCheckBox.Value);
            
            set(app.Read,'Enable','on');
            set(app.FilePath,'Enable','on');


        end

        % Callback function
        function StopButtonPushed(app, event)
            app.isScanning = 0;
            %             set(app.StartButton,'Enable','on');
            %             set(app.StopButton,'Enable','off');
            %             set(app.HoldStateButton,'Enable','off');
            %
            %             set(app.XCheckBox,'Enable','on');
            %             SetXIndexState(app,app.XCheckBox.Value);
            %
            %             set(app.YCheckBox,'Enable','on');
            %             SetYIndexState(app,app.YCheckBox.Value);
            %
            %             set(app.ZCheckBox,'Enable','on');
            %             SetZIndexState(app,app.ZCheckBox.Value);
            %
            %             set(app.Read,'Enable','on');
            %             set(app.FilePath,'Enable','on');
        end

        % Value changed function: InstrDropDown
        function InstrDropDownValueChanged(app, event)
            value = app.InstrDropDown.Value;
            switch value
                case '-'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                case 'lockin 1'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','ON');
                case 'lockin 2'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','ON');
                case 'itek'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','ON');
                case 'ips3'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','ON');
                case 'a33522a'
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.A33522aPanel,'Visible','ON');
                otherwise
                    errordlg('error!');
            end
        end

        % Value changed function: A1CheckBox
        function A1CheckBoxValueChanged(app, event)
            value = app.A1CheckBox.Value;
            set(app.A1EditField,'Enable',value);
        end

        % Value changed function: A2CheckBox
        function A2CheckBoxValueChanged(app, event)
            value = app.A2CheckBox.Value;
            set(app.A2EditField,'Enable',value);
        end

        % Value changed function: A3CheckBox
        function A3CheckBoxValueChanged(app, event)
            value = app.A3CheckBox.Value;
            set(app.A3EditField,'Enable',value);
        end

        % Value changed function: A4CheckBox
        function A4CheckBoxValueChanged(app, event)
            value = app.A4CheckBox.Value;
            set(app.A4EditField,'Enable',value);
        end

        % Value changed function: Amp1CheckBox
        function Amp1CheckBoxValueChanged(app, event)
            value = app.Amp1CheckBox.Value;
            set(app.Amp1EditField,'Enable',value);
        end

        % Value changed function: Freq1CheckBox
        function Freq1CheckBoxValueChanged(app, event)
            value = app.Freq1CheckBox.Value;
            set(app.Freq1EditField,'Enable',value);
        end

        % Value changed function: X1CheckBox
        function X1CheckBoxValueChanged(app, event)
            value = app.X1CheckBox.Value;
            set(app.X1EditField,'Enable',value);
        end

        % Value changed function: Sens1CheckBox
        function Sens1CheckBoxValueChanged(app, event)
            value = app.Sens1CheckBox.Value;
            set(app.Sens1DropDown,'Enable',value);
        end

        % Value changed function: All1CheckBox
        function All1CheckBoxValueChanged(app, event)
            value = app.All1CheckBox.Value;
            
            set(app.A1CheckBox,'Value',value);
            set(app.A2CheckBox,'Value',value);
            set(app.A3CheckBox,'Value',value);
            set(app.A4CheckBox,'Value',value);
            set(app.Amp1CheckBox,'Value',value);
            set(app.Freq1CheckBox,'Value',value);
            set(app.X1CheckBox,'Value',value);
            set(app.Sens1CheckBox,'Value',value);
            
            
            set(app.A1EditField,'Enable',value);
            set(app.A2EditField,'Enable',value);
            set(app.A3EditField,'Enable',value);
            set(app.A4EditField,'Enable',value);
            set(app.Amp1EditField,'Enable',value);
            set(app.Freq1EditField,'Enable',value);
            set(app.X1EditField,'Enable',value);
            set(app.Sens1DropDown,'Enable',value);
        end

        % Value changed function: B1CheckBox
        function B1CheckBoxValueChanged(app, event)
            value = app.B1CheckBox.Value;
            set(app.B1EditField,'Enable',value);
        end

        % Value changed function: B2CheckBox
        function B2CheckBoxValueChanged(app, event)
            value = app.B2CheckBox.Value;
            set(app.B2EditField,'Enable',value);
        end

        % Value changed function: B3CheckBox
        function B3CheckBoxValueChanged(app, event)
            value = app.B3CheckBox.Value;
            set(app.B3EditField,'Enable',value);
        end

        % Value changed function: B4CheckBox
        function B4CheckBoxValueChanged(app, event)
            value = app.B4CheckBox.Value;
            set(app.B4EditField,'Enable',value);
        end

        % Value changed function: Amp2CheckBox
        function Amp2CheckBoxValueChanged(app, event)
            value = app.Amp2CheckBox.Value;
            set(app.Amp2EditField,'Enable',value);
        end

        % Value changed function: Freq2CheckBox
        function Freq2CheckBoxValueChanged(app, event)
            value = app.Freq2CheckBox.Value;
            set(app.Freq2EditField,'Enable',value);
        end

        % Value changed function: X2CheckBox
        function X2CheckBoxValueChanged(app, event)
            value = app.X2CheckBox.Value;
            set(app.X2EditField,'Enable',value);
        end

        % Value changed function: Sens2CheckBox
        function Sens2CheckBoxValueChanged(app, event)
            value = app.Sens2CheckBox.Value;
            set(app.Sens2DropDown,'Enable',value);
        end

        % Value changed function: All2CheckBox
        function All2CheckBoxValueChanged(app, event)
            value = app.All2CheckBox.Value;
            set(app.B1CheckBox,'Value',value);
            set(app.B2CheckBox,'Value',value);
            set(app.B3CheckBox,'Value',value);
            set(app.B4CheckBox,'Value',value);
            set(app.Amp2CheckBox,'Value',value);
            set(app.Freq2CheckBox,'Value',value);
            set(app.X2CheckBox,'Value',value);
            set(app.Sens2CheckBox,'Value',value);
            
            
            set(app.B1EditField,'Enable',value);
            set(app.B2EditField,'Enable',value);
            set(app.B3EditField,'Enable',value);
            set(app.B4EditField,'Enable',value);
            set(app.Amp2EditField,'Enable',value);
            set(app.Freq2EditField,'Enable',value);
            set(app.X2EditField,'Enable',value);
            set(app.Sens2DropDown,'Enable',value);
        end

        % Value changed function: U1CheckBox
        function U1CheckBoxValueChanged(app, event)
            value = app.U1CheckBox.Value;
            set(app.U1EditField,'Enable',value);
        end

        % Value changed function: U2CheckBox
        function U2CheckBoxValueChanged(app, event)
            value = app.U2CheckBox.Value;
            set(app.U2EditField,'Enable',value);
        end

        % Value changed function: U3CheckBox
        function U3CheckBoxValueChanged(app, event)
            value = app.U3CheckBox.Value;
            set(app.U3EditField,'Enable',value);
        end

        % Value changed function: U4CheckBox
        function U4CheckBoxValueChanged(app, event)
            value = app.U4CheckBox.Value;
            set(app.U4EditField,'Enable',value);
        end

        % Value changed function: U5CheckBox
        function U5CheckBoxValueChanged(app, event)
            value = app.U5CheckBox.Value;
            set(app.U5EditField,'Enable',value);
        end

        % Value changed function: U6CheckBox
        function U6CheckBoxValueChanged(app, event)
            value = app.U6CheckBox.Value;
            set(app.U6EditField,'Enable',value);
        end

        % Value changed function: U7CheckBox
        function U7CheckBoxValueChanged(app, event)
            value = app.U7CheckBox.Value;
            set(app.U7EditField,'Enable',value);
        end

        % Value changed function: U8CheckBox
        function U8CheckBoxValueChanged(app, event)
            value = app.U8CheckBox.Value;
            set(app.U8EditField,'Enable',value);
        end

        % Value changed function: U9CheckBox
        function U9CheckBoxValueChanged(app, event)
            value = app.U9CheckBox.Value;
            set(app.U9EditField,'Enable',value);
        end

        % Value changed function: U10CheckBox
        function U10CheckBoxValueChanged(app, event)
            value = app.U10CheckBox.Value;
            set(app.U10EditField,'Enable',value);
        end

        % Value changed function: U11CheckBox
        function U11CheckBoxValueChanged(app, event)
            value = app.U11CheckBox.Value;
            set(app.U11EditField,'Enable',value);
        end

        % Value changed function: U12CheckBox
        function U12CheckBoxValueChanged(app, event)
            value = app.U12CheckBox.Value;
            set(app.U12EditField,'Enable',value);
        end

        % Value changed function: U13CheckBox
        function U13CheckBoxValueChanged(app, event)
            value = app.U13CheckBox.Value;
            set(app.U13EditField,'Enable',value);
        end

        % Value changed function: U14CheckBox
        function U14CheckBoxValueChanged(app, event)
            value = app.U14CheckBox.Value;
            set(app.U14EditField,'Enable',value);
        end

        % Value changed function: U15CheckBox
        function U15CheckBoxValueChanged(app, event)
            value = app.U15CheckBox.Value;
            set(app.U15EditField,'Enable',value);
        end

        % Value changed function: U16CheckBox
        function U16CheckBoxValueChanged(app, event)
            value = app.U16CheckBox.Value;
            set(app.U16EditField,'Enable',value);
        end

        % Value changed function: All3CheckBox
        function All3CheckBoxValueChanged(app, event)
            value = app.All3CheckBox.Value;
            for i = 1:16
                set(eval(sprintf('app.U%dEditField',i)),'Enable',value);
                set(eval(sprintf('app.U%dCheckBox',i)),'Value',value);
            end
        end

        % Value changed function: XtCheckBox
        function XtCheckBoxValueChanged(app, event)
            value = app.XtCheckBox.Value;
            set(app.XtEditField,'Enable',value);
        end

        % Value changed function: ZtCheckBox
        function ZtCheckBoxValueChanged(app, event)
            value = app.ZtCheckBox.Value;
            set(app.ZtEditField,'Enable',value);
        end

        % Value changed function: XfCheckBox
        function XfCheckBoxValueChanged(app, event)
            value = app.XfCheckBox.Value;
            set(app.XfEditField,'Enable',value);
        end

        % Value changed function: ZfCheckBox
        function ZfCheckBoxValueChanged(app, event)
            value = app.ZfCheckBox.Value;
            set(app.ZexEditField,'Enable',value);
            set(app.ZinEditField,'Enable',value);
        end

        % Value changed function: HeatCheckBox
        function HeatCheckBoxValueChanged(app, event)
            value = app.HeatCheckBox.Value;
            set(app.HeatButton,'Enable',value);
        end

        % Value changed function: HoldCheckBox
        function HoldCheckBoxValueChanged(app, event)
            value = app.HoldCheckBox.Value;
            set(app.HoldButton,'Enable',value);
        end

        % Value changed function: ToZeroCheckBox
        function ToZeroCheckBoxValueChanged(app, event)
            value = app.ToZeroCheckBox.Value;
            set(app.ToZeroButton,'Enable',value);
        end

        % Value changed function: All4CheckBox
        function All4CheckBoxValueChanged(app, event)
            value = app.All4CheckBox.Value;
            set(app.XtCheckBox,'Value',value);
            set(app.ZtCheckBox,'Value',value);
            set(app.XfCheckBox,'Value',value);
            set(app.ZfCheckBox,'Value',value);
            set(app.HeatCheckBox,'Value',value);
            set(app.HoldCheckBox,'Value',value);
            set(app.ToZeroCheckBox,'Value',value);
            
            
            set(app.XtEditField,'Enable',value);
            set(app.ZtEditField,'Enable',value);
            set(app.XfEditField,'Enable',value);
            set(app.ZexEditField,'Enable',value);
            set(app.ZinEditField,'Enable',value);
            set(app.HeatButton,'Enable',value);
            set(app.HoldButton,'Enable',value);
            set(app.ToZeroButton,'Enable',value);
        end

        % Value changed function: HeatButton
        function HeatButtonValueChanged(app, event)
            
            %turn off ips3 panel
            set(app.XtCheckBox,'Enable','off');
            set(app.ZtCheckBox,'Enable','off');
            set(app.XfCheckBox,'Enable','off');
            set(app.ZfCheckBox,'Enable','off');
            set(app.HeatCheckBox,'Enable','off');
            set(app.HoldCheckBox,'Enable','off');
            set(app.ToZeroCheckBox,'Enable','off');
            set(app.All4CheckBox,'Enable','off');
            
            set(app.XtEditField,'Enable','off');
            set(app.ZtEditField,'Enable','off');
            set(app.XfEditField,'Enable','off');
            set(app.ZexEditField,'Enable','off');
            set(app.ZinEditField,'Enable','off');
            set(app.HeatButton,'Enable','off');
            set(app.HoldButton,'Enable','off');
            set(app.ToZeroButton,'Enable','off');
            
            set(app.InitButton,'Enable','off');
            set(app.ReadButton,'Enable','off');
            
            %set parameters
            value = app.HeatButton.Text;
            app.ips3.open_init();
            if strcmp(value,'?')||strcmp(value,'OFF')
                if app.ips3.heater_on_z
                    set(app.HeatButton,'Text','ON');
                end
            elseif strcmp(value,'ON')
                if app.ips3.heater_off_z
                    set(app.HeatButton,'Text','OFF');
                end
            end
            app.ips3.close_init();


            %turn on ips3 panel
            set(app.XtCheckBox,'Enable','on');
            set(app.ZtCheckBox,'Enable','on');
            set(app.XfCheckBox,'Enable','on');
            set(app.ZfCheckBox,'Enable','on');
            set(app.HeatCheckBox,'Enable','on');
            set(app.HoldCheckBox,'Enable','on');
            set(app.ToZeroCheckBox,'Enable','on');
            set(app.All4CheckBox,'Enable','on');
            
            set(app.XtEditField,'Enable',app.XtCheckBox.Value);
            set(app.ZtEditField,'Enable',app.ZtCheckBox.Value);
            set(app.XfEditField,'Enable',app.XfCheckBox.Value);
            set(app.ZexEditField,'Enable',app.ZfCheckBox.Value);
            set(app.ZinEditField,'Enable',app.ZfCheckBox.Value);
            set(app.HeatButton,'Enable',app.HeatCheckBox.Value);
            set(app.HoldButton,'Enable',app.HoldCheckBox.Value);
            set(app.ToZeroButton,'Enable',app.ToZeroCheckBox.Value);
            
            set(app.InitButton,'Enable','on');
            set(app.ReadButton,'Enable','on');
            
            
        end

        % Value changed function: HoldButton
        function HoldButtonValueChanged(app, event)
            value = app.HoldButton.Text;
            app.ips3.open_init();
            if strcmp(value,'?')||strcmp(value,'OFF')
                app.ips3.hold;
                set(app.HoldButton,'Text','ON');
            elseif strcmp(value,'ON')
                app.ips3.clmp;
                set(app.HoldButton,'Text','OFF');
            end
            app.ips3.close_init();
        end

        % Value changed function: ToZeroButton
        function ToZeroButtonValueChanged(app, event)
            %turn off ips3 panel
            set(app.XtCheckBox,'Enable','off');
            set(app.ZtCheckBox,'Enable','off');
            set(app.XfCheckBox,'Enable','off');
            set(app.ZfCheckBox,'Enable','off');
            set(app.HeatCheckBox,'Enable','off');
            set(app.HoldCheckBox,'Enable','off');
            set(app.ToZeroCheckBox,'Enable','off');
            set(app.All4CheckBox,'Enable','off');
            
            set(app.XtEditField,'Enable','off');
            set(app.ZtEditField,'Enable','off');
            set(app.XfEditField,'Enable','off');
            set(app.ZexEditField,'Enable','off');
            set(app.ZinEditField,'Enable','off');
            set(app.HeatButton,'Enable','off');
            set(app.HoldButton,'Enable','off');
            set(app.ToZeroButton,'Enable','off');
            
            set(app.InitButton,'Enable','off');
            set(app.ReadButton,'Enable','off');
            
            
            app.ips3.open_init();
            app.ips3.to_zero_x();
            app.ips3.to_zero_z();
            app.ips3.close_init();
            set(app.ToZeroButton,'Value',0);
            
            %turn on ips3 panel
            set(app.XtCheckBox,'Enable','on');
            set(app.ZtCheckBox,'Enable','on');
            set(app.XfCheckBox,'Enable','on');
            set(app.ZfCheckBox,'Enable','on');
            set(app.HeatCheckBox,'Enable','on');
            set(app.HoldCheckBox,'Enable','on');
            set(app.ToZeroCheckBox,'Enable','on');
            set(app.All4CheckBox,'Enable','on');
            
            set(app.XtEditField,'Enable',app.XtCheckBox.Value);
            set(app.ZtEditField,'Enable',app.ZtCheckBox.Value);
            set(app.XfEditField,'Enable',app.XfCheckBox.Value);
            set(app.ZexEditField,'Enable',app.ZfCheckBox.Value);
            set(app.ZinEditField,'Enable',app.ZfCheckBox.Value);
            set(app.HeatButton,'Enable',app.HeatCheckBox.Value);
            set(app.HoldButton,'Enable',app.HoldCheckBox.Value);
            set(app.ToZeroButton,'Enable',app.ToZeroCheckBox.Value);
            
            set(app.InitButton,'Enable','on');
            set(app.ReadButton,'Enable','on');
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed_Init(app, event)
            set(app.InitButton,'Enable','off');
            set(app.ReadButton,'Enable','off');
            flag = 0;
            switch app.InstrDropDown.Value
                case 'lockin 1'
                    if app.lockin1.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.lockin1.open();
                    end
                    
                    %                     app.lockin1.open();
                    for i = 1:4
                        if eval(sprintf('app.A%dCheckBox.Value',i))
                            set(eval(sprintf('app.A%dEditField',i)),'Value',app.lockin1.read_aux(i));
                        end
                    end
                    if app.Sens1CheckBox.Value
                        if app.lockin1.check_input_mode()
                            set(app.Sens1DropDown,'Items',app.sensV);
                        else
                            set(app.Sens1DropDown,'Items',app.sensI);
                        end
                        
                        sens = app.lockin1.read_sens();
                        set(app.Sens1DropDown,'Value',app.Sens1DropDown.Items(sens+1));
                    end
                    if app.Amp1CheckBox.Value
                        set(app.Amp1EditField,'Value',app.lockin1.read_amp());
                    end
                    if app.Freq1CheckBox.Value
                        set(app.Freq1EditField,'Value',app.lockin1.read_freq());
                    end
                    if app.X1CheckBox.Value
                        set(app.X1EditField,'Value',app.lockin1.read_current(1));
                    end
                    
                    if ~flag
                        app.lockin1.close();
                    end
                    
                case 'lockin 2'
                    if app.lockin2.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.lockin2.open();
                    end
                    %                     app.lockin2.open();
                    for i = 1:4
                        if eval(sprintf('app.B%dCheckBox.Value',i))
                            set(eval(sprintf('app.B%dEditField',i)),'Value',app.lockin2.read_aux(i));
                        end
                    end
                    if app.Sens2CheckBox.Value
                        if app.lockin2.check_input_mode()
                            set(app.Sens2DropDown,'Items',app.sensV);
                        else
                            set(app.Sens2DropDown,'Items',app.sensI);
                        end
                        
                        sens = app.lockin2.read_sens();
                        set(app.Sens2DropDown,'Value',app.Sens2DropDown.Items(sens+1));
                    end
                    if app.Amp2CheckBox.Value
                        set(app.Amp2EditField,'Value',app.lockin2.read_amp());
                    end
                    if app.Freq2CheckBox.Value
                        set(app.Freq2EditField,'Value',app.lockin2.read_freq());
                    end
                    if app.X2CheckBox.Value
                        set(app.X2EditField,'Value',app.lockin2.read_current(1));
                    end
                    if ~flag
                        app.lockin2.close();
                    end
                    %                     app.lockin2.close();
                    
                case 'itek'
                    if app.itek.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.itek.open();
                    end
                    %                     app.itek.open();
                    for i = 1:16
                        if eval(sprintf('app.U%dCheckBox.Value',i))
                            set(eval(sprintf('app.U%dEditField',i)),'Value',app.itek.query_volt(i));
                        end
                    end
                    if ~flag
                        app.itek.close();
                    end
                    %                     app.itek.close();
                    
                case 'ips3'
                    if app.ips3.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.ips3.open_init();
                    end
                    %                     app.ips3.open_init();
                    if app.XtCheckBox.Value
                        set(app.XtEditField,'Value',app.ips3.read_field_ramp_rate_x());
                    end
                    if app.ZtCheckBox.Value
                        set(app.ZtEditField,'Value',app.ips3.read_field_ramp_rate_z());
                    end
                    if app.XfCheckBox.Value
                        set(app.XfEditField,'Value',app.ips3.read_field_x());
                    end
                    if app.ZfCheckBox.Value
                        set(app.ZexEditField,'Value',app.ips3.read_field_z());
                        set(app.ZinEditField,'Value',app.ips3.read_persistent_field_z());
                    end
                    if app.HeatCheckBox.Value
                        if app.ips3.check_heater_state_z()
                            set(app.HeatButton,'Text','ON');
                        else
                            set(app.HeatButton,'Text','OFF');
                        end
                    end
                    if app.HoldCheckBox.Value
                        if app.ips3.hold_check()
                            set(app.HoldButton,'Text','ON');
                        else
                            set(app.HoldButton,'Text','OFF');
                        end
                    end
                    if ~flag
                        app.ips3.close_init();
                    end
                    %                     app.ips3.close_init();
                    
                case 'a33522a'
                    if app.a33522a.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.a33522a.open();
                    end
                    for i = 1:2
                        if eval(sprintf('app.Ch%dFreqCheckBox.Value',i))
                            set(eval(sprintf('app.Ch%dFreqEditField',i)),'Value',app.a33522a.query_freq(i));
                        end
                        if eval(sprintf('app.Ch%dAmpCheckBox.Value',i))
                            set(eval(sprintf('app.Ch%dAmpEditField',i)),'Value',app.a33522a.query_amp(i));
                        end
                        if eval(sprintf('app.Ch%dPrdCheckBox.Value',i))
                            set(eval(sprintf('app.Ch%dPrdEditField',i)),'Value',app.a33522a.query_period(i));
                        end
                    end
                    if ~flag
                        app.a33522a.close();
                    end


            end
            pause(0.5);
            set(app.InitButton,'Enable','on');
            set(app.ReadButton,'Enable','on');
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            app.isInit = 1;
            flag = 0;
            switch app.InstrDropDown.Value
                case 'lockin 1'
                    % turn off lockin 1 panel
                    set(app.A1CheckBox,'Enable','off');
                    set(app.A2CheckBox,'Enable','off');
                    set(app.A3CheckBox,'Enable','off');
                    set(app.A4CheckBox,'Enable','off');
                    set(app.Amp1CheckBox,'Enable','off');
                    set(app.Freq1CheckBox,'Enable','off');
                    set(app.X1CheckBox,'Enable','off');
                    set(app.Sens1CheckBox,'Enable','off');
                    set(app.All1CheckBox,'Enable','off');
                    
                    
                    set(app.A1EditField,'Enable','off');
                    set(app.A2EditField,'Enable','off');
                    set(app.A3EditField,'Enable','off');
                    set(app.A4EditField,'Enable','off');
                    set(app.Amp1EditField,'Enable','off');
                    set(app.Freq1EditField,'Enable','off');
                    set(app.X1EditField,'Enable','off');
                    set(app.Sens1DropDown,'Enable','off');
                    
                    set(app.InitButton,'Enable','off');
                    set(app.ReadButton,'Enable','off');
                    set(app.StopButton_2,'Enable','on');
                    
                    
                    %set parameters
                    %                     app.lockin1.open();
                    if app.lockin1.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.lockin1.open();
                    end
                    %A1 ~ A4
                    for i = 1:4
                        if eval(sprintf('app.A%dCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.A%dEditField.Value',i));
                                edit = sprintf('app.A%dEditField',i);
                                reader = @()(app.lockin1.read_aux(i));
                                setter = @(val)(app.lockin1.write_aux(i,val));
                                init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                            end
                        end
                    end
                    %Amp
                    if app.Amp1CheckBox.Value
                        if app.isInit
                            value = app.Amp1EditField.Value;
                            edit = 'app.Amp1EditField';
                            reader = @()(app.lockin1.read_amp);
                            setter = @(val)(app.lockin1.write_amp(val));
                            init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                        end
                    end
                    %Freq
                    if app.Freq1CheckBox.Value
                        if app.isInit
                            value = app.Freq1EditField.Value;
                            edit = 'app.Freq1EditField';
                            reader = @()(app.lockin1.read_freq);
                            setter = @(val)(app.lockin1.write_freq(val));
                            init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                        end
                    end
                    %Sens
                    if app.Sens1CheckBox.Value
                        if app.isInit
                            [~, sens] = ismember(app.Sens1DropDown.Value,app.sensI);
                            if ~sens
                                [~, sens] = ismember(app.Sens1DropDown.Value,app.sensV);
                                if ~sens
                                    errordlg('error');
                                end
                            end
                            app.lockin1.write_sens(sens-1);
                        end
                    end
                    
                    
                    if ~flag
                        app.lockin1.close();
                    end
                    %                     app.lockin1.close();
                    
                    %turn on lockin 1 panel
                    set(app.A1CheckBox,'Enable','on');
                    set(app.A2CheckBox,'Enable','on');
                    set(app.A3CheckBox,'Enable','on');
                    set(app.A4CheckBox,'Enable','on');
                    set(app.Amp1CheckBox,'Enable','on');
                    set(app.Freq1CheckBox,'Enable','on');
                    set(app.X1CheckBox,'Enable','on');
                    set(app.Sens1CheckBox,'Enable','on');
                    set(app.All1CheckBox,'Enable','on');
                    
                    
                    set(app.A1EditField,'Enable',app.A1CheckBox.Value);
                    set(app.A2EditField,'Enable',app.A2CheckBox.Value);
                    set(app.A3EditField,'Enable',app.A3CheckBox.Value);
                    set(app.A4EditField,'Enable',app.A4CheckBox.Value);
                    set(app.Amp1EditField,'Enable',app.Amp1CheckBox.Value);
                    set(app.Freq1EditField,'Enable',app.Freq1CheckBox.Value);
                    set(app.X1EditField,'Enable',app.X1CheckBox.Value);
                    set(app.Sens1DropDown,'Enable',app.Sens1CheckBox.Value);
                    
                    set(app.StopButton_2,'Enable','off');
                    set(app.ReadButton,'Enable','on');
                    set(app.InitButton,'Enable','on');
                    
                    
                case 'lockin 2'
                    % turn off lockin 2 panel
                    set(app.B1CheckBox,'Enable','off');
                    set(app.B2CheckBox,'Enable','off');
                    set(app.B3CheckBox,'Enable','off');
                    set(app.B4CheckBox,'Enable','off');
                    set(app.Amp2CheckBox,'Enable','off');
                    set(app.Freq2CheckBox,'Enable','off');
                    set(app.X2CheckBox,'Enable','off');
                    set(app.Sens2CheckBox,'Enable','off');
                    set(app.All2CheckBox,'Enable','off');
                    
                    
                    set(app.B1EditField,'Enable','off');
                    set(app.B2EditField,'Enable','off');
                    set(app.B3EditField,'Enable','off');
                    set(app.B4EditField,'Enable','off');
                    set(app.Amp2EditField,'Enable','off');
                    set(app.Freq2EditField,'Enable','off');
                    set(app.X2EditField,'Enable','off');
                    set(app.Sens2DropDown,'Enable','off');
                    
                    set(app.InitButton,'Enable','off');
                    set(app.ReadButton,'Enable','off');
                    set(app.StopButton_2,'Enable','on');
                    
                    
                    %set parameters
                    %                     app.lockin2.open();
                    if app.lockin2.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.lockin2.open();
                    end
                    %B1 ~ B4
                    for i = 1:4
                        if eval(sprintf('app.B%dCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.B%dEditField.Value',i));
                                edit = sprintf('app.B%dEditField',i);
                                reader = @()(app.lockin2.read_aux(i));
                                setter = @(val)(app.lockin2.write_aux(i,val));
                                init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                            end
                        end
                    end
                    %Amp
                    if app.Amp2CheckBox.Value
                        if app.isInit
                            value = app.Amp2EditField.Value;
                            edit = 'app.Amp2EditField';
                            reader = @()(app.lockin2.read_amp);
                            setter = @(val)(app.lockin2.write_amp(val));
                            init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                        end
                    end
                    %Freq
                    if app.Freq2CheckBox.Value
                        if app.isInit
                            value = app.Freq2EditField.Value;
                            edit = 'app.Freq2EditField';
                            reader = @()(app.lockin2.read_freq);
                            setter = @(val)(app.lockin2.write_freq(val));
                            init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                        end
                    end
                    %Sens
                    if app.Sens2CheckBox.Value
                        if app.isInit
                            [~, sens] = ismember(app.Sens2DropDown.Value,app.sensI);
                            if ~sens
                                [~, sens] = ismember(app.Sens2DropDown.Value,app.sensV);
                                if ~sens
                                    errordlg('error');
                                end
                            end
                            app.lockin2.write_sens(sens-1);
                        end
                    end
                    
                    %                     app.lockin2.close();
                    if ~flag
                        app.lockin2.close();
                    end
                    
                    
                    %turn on lockin 2 panel
                    set(app.B1CheckBox,'Enable','on');
                    set(app.B2CheckBox,'Enable','on');
                    set(app.B3CheckBox,'Enable','on');
                    set(app.B4CheckBox,'Enable','on');
                    set(app.Amp2CheckBox,'Enable','on');
                    set(app.Freq2CheckBox,'Enable','on');
                    set(app.X2CheckBox,'Enable','on');
                    set(app.Sens2CheckBox,'Enable','on');
                    set(app.All2CheckBox,'Enable','on');
                    
                    
                    set(app.B1EditField,'Enable',app.B1CheckBox.Value);
                    set(app.B2EditField,'Enable',app.B2CheckBox.Value);
                    set(app.B3EditField,'Enable',app.B3CheckBox.Value);
                    set(app.B4EditField,'Enable',app.B4CheckBox.Value);
                    set(app.Amp2EditField,'Enable',app.Amp2CheckBox.Value);
                    set(app.Freq2EditField,'Enable',app.Freq2CheckBox.Value);
                    set(app.X2EditField,'Enable',app.X2CheckBox.Value);
                    set(app.Sens2DropDown,'Enable',app.Sens2CheckBox.Value);
                    
                    set(app.StopButton_2,'Enable','off');
                    set(app.ReadButton,'Enable','on');
                    set(app.InitButton,'Enable','on');
                    
                    
                case 'itek'
                    %turn off itek panel
                    for i = 1:16
                        set(eval(sprintf('app.U%dEditField',i)),'Enable','off');
                        set(eval(sprintf('app.U%dCheckBox',i)),'Enable','off');
                    end
                    set(app.All3CheckBox,'Enable','off');
                    set(app.InitButton,'Enable','off');
                    set(app.ReadButton,'Enable','off');
                    set(app.StopButton_2,'Enable','on');
                    
                    %set parameters
                    %                     app.itek.open();
                    if app.itek.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.itek.open();
                    end
                    for i = 1:16
                        if eval(sprintf('app.U%dCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.U%dEditField.Value',i));
                                edit = sprintf('app.U%dEditField',i);
                                reader = @()(app.itek.query_volt(i));
                                setter = @(val)(app.itek.set_volt(i,val));
                                init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                            end
                        end
                    end
                    %                     app.itek.close();
                    if ~flag
                        app.itek.close();
                    end
                    
                    
                    %turn on itek panel
                    set(app.All3CheckBox,'Enable','on');
                    for i = 1:16
                        set(eval(sprintf('app.U%dEditField',i)),'Enable',eval(sprintf('app.U%dCheckBox.Value',i)));
                        set(eval(sprintf('app.U%dCheckBox',i)),'Enable','on');
                    end
                    set(app.StopButton_2,'Enable','off');
                    set(app.ReadButton,'Enable','on');
                    set(app.InitButton,'Enable','on');
                    
                case 'ips3'
                    %turn off ips3 panel
                    set(app.XtCheckBox,'Enable','off');
                    set(app.ZtCheckBox,'Enable','off');
                    set(app.XfCheckBox,'Enable','off');
                    set(app.ZfCheckBox,'Enable','off');
                    set(app.HeatCheckBox,'Enable','off');
                    set(app.HoldCheckBox,'Enable','off');
                    set(app.ToZeroCheckBox,'Enable','off');
                    set(app.All4CheckBox,'Enable','off');
                    
                    set(app.XtEditField,'Enable','off');
                    set(app.ZtEditField,'Enable','off');
                    set(app.XfEditField,'Enable','off');
                    set(app.ZexEditField,'Enable','off');
                    set(app.ZinEditField,'Enable','off');
                    set(app.HeatButton,'Enable','off');
                    set(app.HoldButton,'Enable','off');
                    set(app.ToZeroButton,'Enable','off');
                    
                    set(app.InitButton,'Enable','off');
                    set(app.ReadButton,'Enable','off');
                    set(app.StopButton_2,'Enable','on');
                    
                    %set parameters
                    if app.ips3.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.ips3.open_init();
                    end
                    %                     app.ips3.open();
                    if app.XtCheckBox.Value
                        if app.isInit
                            value = app.XtEditField.Value;
                            edit = 'app.XtEditField';
                            reader = @()(app.ips3.read_field_ramp_rate_x);
                            setter = @(val)(app.ips3.set_field_ramp_rate_x(val));
                            init_instr(app,reader,setter,value,app.resetipsStep,app.resetipsDelay,edit);
                        end
                    end
                    if app.ZtCheckBox.Value
                        if app.isInit
                            value = app.ZtEditField.Value;
                            edit = 'app.ZtEditField';
                            reader = @()(app.ips3.read_field_ramp_rate_z);
                            setter = @(val)(app.ips3.set_field_ramp_rate_z(val));
                            init_instr(app,reader,setter,value,app.resetipsStep,app.resetipsDelay,edit);
                        end
                    end
                    if app.XfCheckBox.Value
                        if app.isInit
                            value = app.XfEditField.Value;
                            edit = 'app.XfEditField';
                            reader = @()(app.ips3.read_field_x);
                            setter = @(val)(app.ips3.set_field_x(val));
                            init_instr(app,reader,setter,value,app.resetipsStep,app.resetipsDelay,edit);
                        end
                    end
                    if app.ZfCheckBox.Value
                        if app.isInit
                            if ~app.ips3.check_heater_state_z()
                                msg = sprintf('CONFIRM: Heater is OFF now, so the persistent field will NOT change');
                                choice = questdlg(msg);
                                if strcmpi(choice,'yes')
                                    value = app.ZexEditField.Value;
                                    edit = 'app.ZexEditField';
                                    reader = @()(app.ips3.read_field_z);
                                    setter = @(val)(app.ips3.set_field_z(val));
                                    init_instr(app,reader,setter,value,app.resetipsStep,app.resetipsDelay,edit);
                                end
                            else
                                msg = sprintf('CONFIRM: Heater is ON now, so the persistent field WILL change');
                                choice = questdlg(msg);
                                if strcmpi(choice,'yes')
                                    value = app.ZexEditField.Value;
                                    edit = 'app.ZexEditField';
                                    reader = @()(app.ips3.read_field_z);
                                    setter = @(val)(app.ips3.set_field_z(val));
                                    init_instr(app,reader,setter,value,app.resetipsStep,app.resetipsDelay,edit);
                                end
                            end
                        end
                    end
                    %                     app.ips3.close();
                    if ~flag
                        app.ips3.close_init();
                    end
                    
                    %turn on ips3 panel
                    set(app.XtCheckBox,'Enable','on');
                    set(app.ZtCheckBox,'Enable','on');
                    set(app.XfCheckBox,'Enable','on');
                    set(app.ZfCheckBox,'Enable','on');
                    set(app.HeatCheckBox,'Enable','on');
                    set(app.HoldCheckBox,'Enable','on');
                    set(app.ToZeroCheckBox,'Enable','on');
                    set(app.All4CheckBox,'Enable','on');
                    
                    set(app.XtEditField,'Enable',app.XtCheckBox.Value);
                    set(app.ZtEditField,'Enable',app.ZtCheckBox.Value);
                    set(app.XfEditField,'Enable',app.XfCheckBox.Value);
                    set(app.ZexEditField,'Enable',app.ZfCheckBox.Value);
                    set(app.ZinEditField,'Enable',app.ZfCheckBox.Value);
                    set(app.HeatButton,'Enable',app.HeatCheckBox.Value);
                    set(app.HoldButton,'Enable',app.HoldCheckBox.Value);
                    set(app.ToZeroButton,'Enable',app.ToZeroCheckBox.Value);
                    
                    set(app.InitButton,'Enable','on');
                    set(app.ReadButton,'Enable','on');
                    set(app.StopButton_2,'Enable','off');
                    
                case 'a33522a'
                    % turn off a33522a panel
                    for i = 1:2
                        set(eval(sprintf('app.Ch%dFreqCheckBox',i)),'Enable','off');
                        set(eval(sprintf('app.Ch%dAmpCheckBox',i)),'Enable','off');
                        set(eval(sprintf('app.Ch%dPrdCheckBox',i)),'Enable','off');
                        
                        set(eval(sprintf('app.Ch%dFreqEditField',i)),'Enable','off');
                        set(eval(sprintf('app.Ch%dAmpEditField',i)),'Enable','off');
                        set(eval(sprintf('app.Ch%dPrdEditField',i)),'Enable','off');
                    end
                    set(app.All5CheckBox,'Enable','off');
                    
                    set(app.InitButton,'Enable','off');
                    set(app.ReadButton,'Enable','off');
                    set(app.StopButton_2,'Enable','on');
                    
                    
                    %set parameters
                    if app.a33522a.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.a33522a.open();
                    end
                    
                    for i = 1:2
                        if eval(sprintf('app.Ch%dFreqCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.Ch%dFreqEditField.Value',i));
                                edit = sprintf('app.Ch%dFreqEditField',i);
                                reader = @()(app.a33522a.query_freq(i));
                                setter = @(val)(app.a33522a.set_freq(i,val));
                                init_instr(app,reader,setter,value,app.stepFreq,app.delayFreq,edit);
                            end
                        end
                        if eval(sprintf('app.Ch%dAmpCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.Ch%dAmpEditField.Value',i));
                                edit = sprintf('app.Ch%dAmpEditField',i);
                                reader = @()(app.a33522a.query_amp(i));
                                setter = @(val)(app.a33522a.set_amp(i,val));
                                init_instr(app,reader,setter,value,app.stepAmp,app.delayAmp,edit);
                            end
                        end
                        if eval(sprintf('app.Ch%dPrdCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.Ch%dPrdEditField.Value',i));
                                edit = sprintf('app.Ch%dPrdEditField',i);
                                reader = @()(app.a33522a.query_period(i));
                                setter = @(val)(app.a33522a.set_period(i,val));
                                init_instr(app,reader,setter,value,1/app.stepFreq,app.delayFreq,edit);
                            end
                        end
                    end
                    
                    if ~flag
                        app.a33522a.close();
                    end
                    
                    
                    %turn on lockin 2 panel
                    for i = 1:2
                        set(eval(sprintf('app.Ch%dFreqCheckBox',i)),'Enable','on');
                        set(eval(sprintf('app.Ch%dAmpCheckBox',i)),'Enable','on');
                        set(eval(sprintf('app.Ch%dPrdCheckBox',i)),'Enable','on');
                        
                        set(eval(sprintf('app.Ch%dFreqEditField',i)),'Enable',eval(sprintf('app.Ch%dFreqCheckBox.Value',i)));
                        set(eval(sprintf('app.Ch%dAmpEditField',i)),'Enable',eval(sprintf('app.Ch%dAmpCheckBox.Value',i)));
                        set(eval(sprintf('app.Ch%dPrdEditField',i)),'Enable',eval(sprintf('app.Ch%dPrdCheckBox.Value',i)));
                    end
                    set(app.All5CheckBox,'Enable','on');
                    
                    set(app.StopButton_2,'Enable','off');
                    set(app.ReadButton,'Enable','on');
                    set(app.InitButton,'Enable','on');
                    
                    
            end
            app.isInit = 0;
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.isInit = 0;
        end

        % Button pushed function: ChangeFilePathButton
        function ChangeFilePathButtonPushed(app, event)
            app.ChangeFilePathButton.Enable = 0;
            try
                dname = uigetdir(app.FilePath.Value);
            catch
                dname = uigetdir('E:\People\HRZ\data\test');
            end
            if dname
                app.FilePath.Value = dname;
            end
            
            app.ChangeFilePathButton.Enable = 1;
        end

        % Button pushed function: xChannel
        function xChannelPushed(app, event)
            instrlist = setdiff(app.InstrDropDown.Items,{'-'});
            tips = 'Select instrment:';
            title = 'Instruments selection';
            [Instr_Index,Instr_tf]= listdlg('ListString', instrlist,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
            if Instr_tf
                switch instrlist{Instr_Index}
                    case 'lockin 1'
                        ChannelList = {'A1','A2','A3','A4','Freq','Amp'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.xChannel.Text = '1';
                                case 2
                                    app.xChannel.Text = '2';
                                case 3
                                    app.xChannel.Text = '3';
                                case 4
                                    app.xChannel.Text = '4';
                                case 5
                                    app.xChannel.Text = '13';
                                case 6
                                    app.xChannel.Text = '14';
                            end
                        end
                    case 'lockin 2'
                        ChannelList = {'B1','B2','B3','B4','Freq','Amp'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.xChannel.Text = '5';
                                case 2
                                    app.xChannel.Text = '6';
                                case 3
                                    app.xChannel.Text = '7';
                                case 4
                                    app.xChannel.Text = '8';
                                case 5
                                    app.xChannel.Text = '15';
                                case 6
                                    app.xChannel.Text = '16';
                            end
                        end
                    case 'itek'
                        ChannelList = {'U1','U2','U3','U4','U5','U6','U7','U8','U9','U10','U11','U12','U13','U14','U15','U16'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            if Ch_Index > 9
                                app.xChannel.Text = sprintf('2%d',Ch_Index);
                            else
                                app.xChannel.Text = sprintf('20%d',Ch_Index);
                            end
                        end
                    case 'ips3'
                        ChannelList = {'X_Field','Z_Field'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.xChannel.Text = '121';
                                case 2
                                    app.xChannel.Text = '122';
                            end
                        end
                    case 'dmm'
                        app.xChannel.Text = '13';
                    case  'a33522a'
                        ChannelList = {'SquareFreq','SquareAmp','SquarePeriod'};
                        tips ='Select Channel@a33522a:';
                        title = 'Channel@a33522a';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100]);
                        if Ch_tf
                            for j = 1:length(Ch_Index)
                                switch Ch_Index(j)
                                    case 1
                                        app.xChannel.Text = '27';
                                    case 2
                                        app.xChannel.Text = '28';
                                    case 3
                                        app.xChannel.Text = '29';
                                end
                            end
                        end
                end
            end
        end

        % Button pushed function: yChannel
        function yChannelPushed(app, event)
            instrlist = setdiff(app.InstrDropDown.Items,{'-'});
            tips = 'Select instrment:';
            title = 'Instruments selection';
            [Instr_Index,Instr_tf]= listdlg('ListString', instrlist,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
            if Instr_tf
                switch instrlist{Instr_Index}
                    case 'lockin 1'
                        ChannelList = {'A1','A2','A3','A4','Freq','Amp'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.yChannel.Text = '1';
                                case 2
                                    app.yChannel.Text = '2';
                                case 3
                                    app.yChannel.Text = '3';
                                case 4
                                    app.yChannel.Text = '4';
                                case 5
                                    app.yChannel.Text = '13';
                                case 6
                                    app.yChannel.Text = '14';
                            end
                        end
                    case 'lockin 2'
                        ChannelList = {'B1','B2','B3','B4','Freq','Amp'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.yChannel.Text = '5';
                                case 2
                                    app.yChannel.Text = '6';
                                case 3
                                    app.yChannel.Text = '7';
                                case 4
                                    app.yChannel.Text = '8';
                                case 5
                                    app.yChannel.Text = '15';
                                case 6
                                    app.yChannel.Text = '16';
                            end
                        end
                    case 'itek'
                        ChannelList = {'U1','U2','U3','U4','U5','U6','U7','U8','U9','U10','U11','U12','U13','U14','U15','U16'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            if Ch_Index > 9
                                app.yChannel.Text = sprintf('2%d',Ch_Index);
                            else
                                app.yChannel.Text = sprintf('20%d',Ch_Index);
                            end
                        end
                    case 'ips3'
                        ChannelList = {'X_Field','Z_Field'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.yChannel.Text = '121';
                                case 2
                                    app.yChannel.Text = '122';
                            end
                        end
                    case 'dmm'
                        app.yChannel.Text = '13';
                    case  'a33522a'
                        ChannelList = {'SquareFreq','SquareAmp','SquarePeriod'};
                        tips ='Select Channel@a33522a:';
                        title = 'Channel@a33522a';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100]);
                        if Ch_tf
                            for j = 1:length(Ch_Index)
                                switch Ch_Index(j)
                                    case 1
                                        app.yChannel.Text = '27';
                                    case 2
                                        app.yChannel.Text = '28';
                                    case 3
                                        app.yChannel.Text = '29';
                                end
                            end
                        end
                end
            end
        end

        % Button pushed function: zChannel
        function zChannelPushed(app, event)
            instrlist = setdiff(app.InstrDropDown.Items,{'-'});
            tips = 'Select instrment:';
            title = 'Instruments selection';
            [Instr_Index,Instr_tf]= listdlg('ListString', instrlist,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
            if Instr_tf
                switch instrlist{Instr_Index}
                    case 'lockin 1'
                        ChannelList = {'A1','A2','A3','A4','Freq','Amp'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.zChannel.Text = '1';
                                case 2
                                    app.zChannel.Text = '2';
                                case 3
                                    app.zChannel.Text = '3';
                                case 4
                                    app.zChannel.Text = '4';
                                case 5
                                    app.zChannel.Text = '13';
                                case 6
                                    app.zChannel.Text = '14';
                            end
                        end
                    case 'lockin 2'
                        ChannelList = {'B1','B2','B3','B4','Freq','Amp'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.zChannel.Text = '5';
                                case 2
                                    app.zChannel.Text = '6';
                                case 3
                                    app.zChannel.Text = '7';
                                case 4
                                    app.zChannel.Text = '8';
                                case 5
                                    app.zChannel.Text = '15';
                                case 6
                                    app.zChannel.Text = '16';
                            end
                        end
                    case 'itek'
                        ChannelList = {'U1','U2','U3','U4','U5','U6','U7','U8','U9','U10','U11','U12','U13','U14','U15','U16'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            if Ch_Index > 9
                                app.zChannel.Text = sprintf('2%d',Ch_Index);
                            else
                                app.zChannel.Text = sprintf('20%d',Ch_Index);
                            end
                        end
                    case 'ips3'
                        ChannelList = {'X_Field','Z_Field'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.zChannel.Text = '121';
                                case 2
                                    app.zChannel.Text = '122';
                            end
                        end
                    case 'dmm'
                        app.zChannel.Text = '13';
                    case  'a33522a'
                        ChannelList = {'SquareFreq','SquareAmp','SquarePeriod'};
                        tips ='Select Channel@a33522a:';
                        title = 'Channel@a33522a';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100]);
                        if Ch_tf
                            for j = 1:length(Ch_Index)
                                switch Ch_Index(j)
                                    case 1
                                        app.zChannel.Text = '27';
                                    case 2
                                        app.zChannel.Text = '28';
                                    case 3
                                        app.zChannel.Text = '29';
                                end
                            end
                        end
                end
            end
        end

        % Button pushed function: Read
        function ReadPushed(app, event)
            instrlist = app.ScanList;
            tips = 'Select instrment:';
            title = 'Instruments selection';
            [Instr_Index,Instr_tf]= listdlg('ListString', instrlist,'PromptString',tips,'Name',title,'ListSize',[300,100]);
            if Instr_tf
                app.Read.Text = '';
                for i = 1:length(Instr_Index)
                    switch instrlist{Instr_Index(i)}
                        case 'lockin 1'
                            ChannelList = {'X','Y','R','theta'};
                            tips ='Select Channel@lockin 1:';
                            title = 'Channel@lockin 1';
                            [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100]);
                            if Ch_tf
                                for j = 1:length(Ch_Index)
                                    switch Ch_Index(j)
                                        case 1
                                            app.Read.Text = [app.Read.Text '1 '];
                                        case 2
                                            app.Read.Text = [app.Read.Text '2 '];
                                        case 3
                                            app.Read.Text = [app.Read.Text '3 '];
                                        case 4
                                            app.Read.Text = [app.Read.Text '4 '];
                                    end
                                end
                            end
                            
                        case 'lockin 2'
                            ChannelList = {'X','Y','R','theta'};
                            tips ='Select Channel@lockin 2:';
                            title = 'Channel@lockin 2';
                            [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100]);
                            if Ch_tf
                                for j = 1:length(Ch_Index)
                                    switch Ch_Index(j)
                                        case 1
                                            app.Read.Text = [app.Read.Text '5 '];
                                        case 2
                                            app.Read.Text = [app.Read.Text '6 '];
                                        case 3
                                            app.Read.Text = [app.Read.Text '7 '];
                                        case 4
                                            app.Read.Text = [app.Read.Text '8 '];
                                    end
                                end
                            end
                            
                        case 'dmm'
                            app.Read.Text = [app.Read.Text '13 '];
                            
                    end
                end
            end
        end

        % Value changed function: All5CheckBox
        function All5CheckBoxValueChanged(app, event)
            value = app.All5CheckBox.Value;
            set(app.Ch1CheckBox,'Value',value);
            set(app.Ch2CheckBox,'Value',value);
            Ch1CheckBoxValueChanged(app, event);
            Ch2CheckBoxValueChanged(app, event);
        end

        % Value changed function: Ch1CheckBox
        function Ch1CheckBoxValueChanged(app, event)
            value = app.Ch1CheckBox.Value;
            set(app.Ch1FreqCheckBox,'Value',value);
            set(app.Ch1AmpCheckBox,'Value',value);
            set(app.Ch1PrdCheckBox,'Value',value);
            
            set(app.Ch1FreqEditField,'Enable',value);
            set(app.Ch1AmpEditField,'Enable',value);
            set(app.Ch1PrdEditField,'Enable',value);
        end

        % Value changed function: Ch2CheckBox
        function Ch2CheckBoxValueChanged(app, event)
            value = app.Ch2CheckBox.Value;
            set(app.Ch2FreqCheckBox,'Value',value);
            set(app.Ch2AmpCheckBox,'Value',value);
            set(app.Ch2PrdCheckBox,'Value',value);
            
            set(app.Ch2FreqEditField,'Enable',value);
            set(app.Ch2AmpEditField,'Enable',value);
            set(app.Ch2PrdEditField,'Enable',value);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if app.StartButton.Value
                app.dim = 0;
                
                %Check Index dimension & get scanning parameters
                if app.XCheckBox.Value
                    if strcmp(app.xChannel.Text,':')
                        errordlg('Check xChannel','Parameter Check','modal');
                        set(app.StartButton,'Value',0);
                        return;
                    elseif ~app.xStep.Value||~app.xDelay.Value
                        errordlg('Check xStep or xDelay','Parameter Check','modal');
                        set(app.StartButton,'Value',0);
                        return;
                    else
                        app.dim = app.dim + 1;
                        app.selectedIndex(1) = 1;
                        app.channel(app.dim) = str2num(app.xChannel.Text);
                        app.from(app.dim) = app.xFrom.Value;
                        app.step(app.dim) = app.xStep.Value;
                        app.to(app.dim) = app.xTo.Value;
                        app.delay(app.dim) = app.xDelay.Value;
                        app.repeat(app.dim) = app.xRepeat.Value;
                    end
                end
                
                if app.YCheckBox.Value
                    if strcmp(app.yChannel.Text,':')
                        errordlg('Check yChannel','Parameter Check','modal');
                        set(app.StartButton,'Value',0);
                        return;
                    elseif ~app.yStep.Value||~app.yDelay.Value
                        errordlg('Check yStep or yDelay','Parameter Check','modal');
                        set(app.StartButton,'Value',0);
                        return;
                    else
                        app.dim = app.dim + 1;
                        app.selectedIndex(2) = 1;
                        app.channel(app.dim) = str2num(app.yChannel.Text);
                        app.from(app.dim) = app.yFrom.Value;
                        app.step(app.dim) = app.yStep.Value;
                        app.to(app.dim) = app.yTo.Value;
                        app.delay(app.dim) = app.yDelay.Value;
                        app.repeat(app.dim) = app.yRepeat.Value;
                    end
                end
                
                if app.ZCheckBox.Value
                    if strcmp(app.zChannel.Text,':')
                        errordlg('Check zChannel','Parameter Check','modal');
                        set(app.StartButton,'Value',0);
                        return;
                    elseif ~app.zStep.Value||~app.zDelay.Value
                        errordlg('Check zStep or zDelay','Parameter Check','modal');
                        set(app.StartButton,'Value',0);
                        return;
                    else
                        app.dim = app.dim + 1;
                        app.selectedIndex(3) = 1;
                        app.channel(app.dim) = str2num(app.zChannel.Text);
                        app.from(app.dim) = app.zFrom.Value;
                        app.step(app.dim) = app.zStep.Value;
                        app.to(app.dim) = app.zTo.Value;
                        app.delay(app.dim) = app.zDelay.Value;
                        app.repeat(app.dim) = app.zRepeat.Value;
                    end
                end
                
                if strcmp(app.Read.Text,':')
                    errordlg('Check Read parameter','Parameter Check','modal');
                    set(app.StartButton,'Value',0);
                    return;
                else
                    app.readParam = str2num(app.Read.Text);
                end
                
                
                if ~app.dim
                    errordlg('no Indexs selected');
                    set(app.StartButton,'Value',0);
                    return;
                end
                
                if ~dispatch_lockin(app)
                    set(app.StartButton,'Value',0);
                    return;
                end
                
                %UI figure off
                set(app.StartButton,'Text','Stop');
                
                set(app.PauseButton,'Enable','on');
                set(app.PauseButton,'Value',0);
                
                set(app.HoldStateButton,'Enable','on');
                set(app.HoldStateButton,'Value',0);
                set(app.HoldStateButton,'Text','Hold OFF');
                
                set(app.XCheckBox,'Enable','off');
                SetXIndexState(app,0);
                
                set(app.YCheckBox,'Enable','off');
                SetYIndexState(app,0);
                
                set(app.ZCheckBox,'Enable','off');
                SetZIndexState(app,0);
                
                set(app.Read,'Enable','off');
                set(app.FilePath,'Enable','off');


                %create data files
                app.filePath = app.FilePath.Value;
                if isempty(app.filePath)
                    errordlg('No File Name');
                    return;
                end
                if ~exist(app.filePath,'dir')
                    mkdir(app.filePath);
                end
                app.originalfilePath=app.filePath;
                datestring=datestr(now,'mmmm-dd-yyyy_HH-MM-SS');
                app.filePath=fullfile(app.filePath,datestring);
                if ~exist(app.filePath,'dir')
                    mkdir(app.filePath);
                end
                
                defaultPath='E:\MATLAB code\semiconductor group\revised08';
                app.defaultMapPath=fullfile(defaultPath,'map.txt');
                
                write_parameter(app);
                
                
                dimr=length(app.readers);
                fw=FileWriter03(app.dim,app.dims+dimr,app.filePath);
                
                for i=1:app.dim
                    app.sweep_conf{i}.instr.open;
                    if app.sweep_conf{i}.resetmode==1
                        init_channel(app,app.sweep_conf{i}.reader,app.sweep_conf{i}.setter,app.sweep_conf{i}.from,app.sweep_conf{i}.resetStep,app.sweep_conf{i}.resetDelay);
                    end
                    for j=1:app.sweep_conf{i}.hasAssoc
                        app.sweep_conf{i}.assoc_conf{j}.instr.open;
                        if app.sweep_conf{i}.assoc_conf{j}.resetmode==1
                            
                            init_channel(app,app.sweep_conf{i}.assoc_conf{j}.reader,app.sweep_conf{i}.assoc_conf{j}.setter,app.sweep_conf{i}.assoc_conf{j}.from,app.sweep_conf{i}.assoc_conf{j}.resetStep,app.sweep_conf{i}.assoc_conf{j}.resetDelay);
                        end
                    end
                end
                for i=1:length(app.lockinRead)
                    app.lockinRead{i}.open;
                end
                app.isScanning = 1;
                Scanner(app, fw);
                app.isScanning = 0;
                
                %             for i=1:app.dim
                %                 if app.sweep_conf{i}.instr~=ips
                %                     app.sweep_conf{i}.instr.close;
                %                 end
                %                 for j=1:app.sweep_conf{i}.hasAssoc
                %                     if app.sweep_conf{i}.assoc_conf{j}.instr~=ips
                %                         app.sweep_conf{i}.assoc_conf{j}.instr.close;
                %                     end
                %                 end
                %             end
                for i=1:length(app.readers)
                    app.lockinRead{i}.close;
                end
                %           if hasAssoc
                %              assoc_conf.instr.close;
                %           end
                delete(fw);
                
                
                %UI figure on
                set(app.StartButton,'Text','Start');
                set(app.StartButton,'Value',0);
                
                set(app.PauseButton,'Enable','off');
                set(app.PauseButton,'Value',0);
                
                set(app.HoldStateButton,'Enable','off');
                
                set(app.XCheckBox,'Enable','on');
                SetXIndexState(app,app.XCheckBox.Value);
                
                set(app.YCheckBox,'Enable','on');
                SetYIndexState(app,app.YCheckBox.Value);
                
                set(app.ZCheckBox,'Enable','on');
                SetZIndexState(app,app.ZCheckBox.Value);
                
                set(app.Read,'Enable','on');
                set(app.FilePath,'Enable','on');
            else
                if app.PauseButton.Value
                    set(app.PauseButton,'Text','Pause');
                    uiresume;
                end
                app.isScanning = 0;
            end
            
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            if app.PauseButton.Value
                set(app.PauseButton,'Text','Resume');
                set(app.HoldStateButton,'Enable','Off');
                uiwait;
            else
                set(app.PauseButton,'Text','Pause');
                set(app.HoldStateButton,'Enable','On');
                uiresume;
            end
        end

        % Value changed function: HoldStateButton
        function HoldStateButtonValueChanged(app, event)
            if app.HoldStateButton.Value
                set(app.HoldStateButton,'Text','Hold ON');
            else
                set(app.HoldStateButton,'Text','Hold OFF');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.ParameterPanel = uipanel(app.ScanTab);
            app.ChannelLabel = uilabel(app.ParameterPanel);
            app.ChannelLabel.Text = 'Channel';
            app.FromLabel = uilabel(app.ParameterPanel);
            app.FromLabel.Text = 'From';
            app.XCheckBox = uicheckbox(app.ParameterPanel);
            app.XCheckBox.ValueChangedFcn = createCallbackFcn(app, @XCheckBoxValueChanged, true);
            app.XCheckBox.Text = 'X';
            app.XCheckBox.Value = true;
            app.xChannel = uibutton(app.ParameterPanel, 'push');
            app.xChannel.ButtonPushedFcn = createCallbackFcn(app, @xChannelPushed, true);
            app.xChannel.Text = ':';
            app.xFrom = uieditfield(app.ParameterPanel, 'numeric');
            app.StepLabel = uilabel(app.ParameterPanel);
            app.StepLabel.Text = 'Step';
            app.xStep = uieditfield(app.ParameterPanel, 'numeric');
            app.ToLabel = uilabel(app.ParameterPanel);
            app.ToLabel.Text = 'To';
            app.xTo = uieditfield(app.ParameterPanel, 'numeric');
            app.YCheckBox = uicheckbox(app.ParameterPanel);
            app.YCheckBox.ValueChangedFcn = createCallbackFcn(app, @YCheckBoxValueChanged, true);
            app.YCheckBox.Text = 'Y';
            app.yChannel = uibutton(app.ParameterPanel, 'push');
            app.yChannel.ButtonPushedFcn = createCallbackFcn(app, @yChannelPushed, true);
            app.yChannel.Enable = 'off';
            app.yChannel.Text = ':';
            app.yFrom = uieditfield(app.ParameterPanel, 'numeric');
            app.yFrom.Enable = 'off';
            app.yStep = uieditfield(app.ParameterPanel, 'numeric');
            app.yStep.Enable = 'off';
            app.yTo = uieditfield(app.ParameterPanel, 'numeric');
            app.yTo.Enable = 'off';
            app.DelayLabel = uilabel(app.ParameterPanel);
            app.DelayLabel.Text = 'Delay';
            app.xDelay = uieditfield(app.ParameterPanel, 'numeric');
            app.yDelay = uieditfield(app.ParameterPanel, 'numeric');
            app.yDelay.Enable = 'off';
            app.RepeatLabel = uilabel(app.ParameterPanel);
            app.RepeatLabel.Text = 'Repeat';
            app.xRepeat = uieditfield(app.ParameterPanel, 'numeric');
            app.xRepeat.Value = 1;
            app.yRepeat = uieditfield(app.ParameterPanel, 'numeric');
            app.yRepeat.Enable = 'off';
            app.yRepeat.Value = 1;
            app.ZCheckBox = uicheckbox(app.ParameterPanel);
            app.ZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZCheckBoxValueChanged, true);
            app.ZCheckBox.Text = 'Z';
            app.zChannel = uibutton(app.ParameterPanel, 'push');
            app.zChannel.ButtonPushedFcn = createCallbackFcn(app, @zChannelPushed, true);
            app.zChannel.Enable = 'off';
            app.zChannel.Text = ':';
            app.zFrom = uieditfield(app.ParameterPanel, 'numeric');
            app.zFrom.Enable = 'off';
            app.zStep = uieditfield(app.ParameterPanel, 'numeric');
            app.zStep.Enable = 'off';
            app.zTo = uieditfield(app.ParameterPanel, 'numeric');
            app.zTo.Enable = 'off';
            app.zDelay = uieditfield(app.ParameterPanel, 'numeric');
            app.zDelay.Enable = 'off';
            app.zRepeat = uieditfield(app.ParameterPanel, 'numeric');
            app.zRepeat.Enable = 'off';
            app.zRepeat.Value = 1;
            app.ReadPanel = uipanel(app.ScanTab);
            app.ChangeFilePathButton = uibutton(app.ReadPanel, 'push');
            app.ChangeFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeFilePathButtonPushed, true);
            app.ChangeFilePathButton.Text = '…';
            app.FilePath = uieditfield(app.ReadPanel, 'text');
            app.FilePath.Value = 'E:\people\HRZ\Si MOS 2018';
            app.Read = uibutton(app.ReadPanel, 'push');
            app.Read.ButtonPushedFcn = createCallbackFcn(app, @ReadPushed, true);
            app.Read.Text = ':';
            app.FilePathLabel = uilabel(app.ReadPanel);
            app.FilePathLabel.Text = 'FilePath';
            app.ReadLabel = uilabel(app.ReadPanel);
            app.ReadLabel.Text = 'Read';
            app.DisableLabel = uilabel(app.ScanTab);
            app.DisableLabel.Enable = 'off';
            app.DisableLabel.Visible = 'off';
            app.DisableLabel.Text = {'Scan Function is '; 'DISABLE now!'};
            app.Button = uipanel(app.ScanTab);
            app.HoldStateButton = uibutton(app.Button, 'state');
            app.HoldStateButton.ValueChangedFcn = createCallbackFcn(app, @HoldStateButtonValueChanged, true);
            app.HoldStateButton.Enable = 'off';
            app.HoldStateButton.Text = 'Hold OFF';
            app.StartButton = uibutton(app.Button, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.Button, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.InitialTab = uitab(app.TabGroup);
            app.InitialTab.Title = 'Initial';
            app.InstrDropDownLabel = uilabel(app.InitialTab);
            app.InstrDropDownLabel.Text = 'Instr';
            app.InstrDropDown = uidropdown(app.InitialTab);
            app.InstrDropDown.Items = {'-', 'lockin 1', 'lockin 2', 'itek', 'ips3'};
            app.InstrDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrDropDownValueChanged, true);
            app.InstrDropDown.Value = '-';
            app.Lockin1Panel = uipanel(app.InitialTab);
            app.Lockin1Panel.Visible = 'off';
            app.A1EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.A1CheckBox = uicheckbox(app.Lockin1Panel);
            app.A1CheckBox.ValueChangedFcn = createCallbackFcn(app, @A1CheckBoxValueChanged, true);
            app.A1CheckBox.Text = 'A1';
            app.A1CheckBox.Value = true;
            app.A2EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.A2CheckBox = uicheckbox(app.Lockin1Panel);
            app.A2CheckBox.ValueChangedFcn = createCallbackFcn(app, @A2CheckBoxValueChanged, true);
            app.A2CheckBox.Text = 'A2';
            app.A2CheckBox.Value = true;
            app.A3EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.A3CheckBox = uicheckbox(app.Lockin1Panel);
            app.A3CheckBox.ValueChangedFcn = createCallbackFcn(app, @A3CheckBoxValueChanged, true);
            app.A3CheckBox.Text = 'A3';
            app.A3CheckBox.Value = true;
            app.A4EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.A4CheckBox = uicheckbox(app.Lockin1Panel);
            app.A4CheckBox.ValueChangedFcn = createCallbackFcn(app, @A4CheckBoxValueChanged, true);
            app.A4CheckBox.Text = 'A4';
            app.A4CheckBox.Value = true;
            app.Amp1EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.Amp1CheckBox = uicheckbox(app.Lockin1Panel);
            app.Amp1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Amp1CheckBoxValueChanged, true);
            app.Amp1CheckBox.Text = 'Amp';
            app.Amp1CheckBox.Value = true;
            app.Freq1EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.Freq1CheckBox = uicheckbox(app.Lockin1Panel);
            app.Freq1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Freq1CheckBoxValueChanged, true);
            app.Freq1CheckBox.Text = 'Freq';
            app.Freq1CheckBox.Value = true;
            app.X1EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.X1EditField.Editable = 'off';
            app.X1CheckBox = uicheckbox(app.Lockin1Panel);
            app.X1CheckBox.ValueChangedFcn = createCallbackFcn(app, @X1CheckBoxValueChanged, true);
            app.X1CheckBox.Text = 'X';
            app.X1CheckBox.Value = true;
            app.Sens1CheckBox = uicheckbox(app.Lockin1Panel);
            app.Sens1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sens1CheckBoxValueChanged, true);
            app.Sens1CheckBox.Text = 'Sens';
            app.Sens1CheckBox.Value = true;
            app.Sens1DropDown = uidropdown(app.Lockin1Panel);
            app.Sens1DropDown.Items = {'2nA', '5nA', '10nA', '20nA', '50nA', '100nA', '200nA', '500nA', '1uA', '2uA', '5uA', '10uA', '20uA', '50uA', '100uA', '200uA', '500uA', '1mA', '2mA', '5mA', '10mA', '20mA', '50mA', '100mA', '200mA', '500mA', '1A'};
            app.Sens1DropDown.Value = '200mA';
            app.All1CheckBox = uicheckbox(app.Lockin1Panel);
            app.All1CheckBox.ValueChangedFcn = createCallbackFcn(app, @All1CheckBoxValueChanged, true);
            app.All1CheckBox.Text = 'All';
            app.All1CheckBox.Value = true;
            app.ReadButton = uibutton(app.InitialTab, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed_Init, true);
            app.ReadButton.Text = 'Read';
            app.InitButton = uibutton(app.InitialTab, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.StopButton_2 = uibutton(app.InitialTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Enable = 'off';
            app.StopButton_2.Text = 'Stop';
            app.Lockin2Panel = uipanel(app.InitialTab);
            app.Lockin2Panel.Visible = 'off';
            app.B1EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.B1CheckBox = uicheckbox(app.Lockin2Panel);
            app.B1CheckBox.ValueChangedFcn = createCallbackFcn(app, @B1CheckBoxValueChanged, true);
            app.B1CheckBox.Text = 'B1';
            app.B1CheckBox.Value = true;
            app.B2EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.B2CheckBox = uicheckbox(app.Lockin2Panel);
            app.B2CheckBox.ValueChangedFcn = createCallbackFcn(app, @B2CheckBoxValueChanged, true);
            app.B2CheckBox.Text = 'B2';
            app.B2CheckBox.Value = true;
            app.B3EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.B3CheckBox = uicheckbox(app.Lockin2Panel);
            app.B3CheckBox.ValueChangedFcn = createCallbackFcn(app, @B3CheckBoxValueChanged, true);
            app.B3CheckBox.Text = 'B3';
            app.B3CheckBox.Value = true;
            app.B4EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.B4CheckBox = uicheckbox(app.Lockin2Panel);
            app.B4CheckBox.ValueChangedFcn = createCallbackFcn(app, @B4CheckBoxValueChanged, true);
            app.B4CheckBox.Text = 'B4';
            app.B4CheckBox.Value = true;
            app.Amp2EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.Amp2CheckBox = uicheckbox(app.Lockin2Panel);
            app.Amp2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Amp2CheckBoxValueChanged, true);
            app.Amp2CheckBox.Text = 'Amp';
            app.Amp2CheckBox.Value = true;
            app.Freq2EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.Freq2CheckBox = uicheckbox(app.Lockin2Panel);
            app.Freq2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Freq2CheckBoxValueChanged, true);
            app.Freq2CheckBox.Text = 'Freq';
            app.Freq2CheckBox.Value = true;
            app.X2EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.X2CheckBox = uicheckbox(app.Lockin2Panel);
            app.X2CheckBox.ValueChangedFcn = createCallbackFcn(app, @X2CheckBoxValueChanged, true);
            app.X2CheckBox.Text = 'X';
            app.X2CheckBox.Value = true;
            app.Sens2CheckBox = uicheckbox(app.Lockin2Panel);
            app.Sens2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sens2CheckBoxValueChanged, true);
            app.Sens2CheckBox.Text = 'Sens';
            app.Sens2CheckBox.Value = true;
            app.Sens2DropDown = uidropdown(app.Lockin2Panel);
            app.Sens2DropDown.Items = {'2nA', '5nA', '10nA', '20nA', '50nA', '100nA', '200nA', '500nA', '1uA', '2uA', '5uA', '10uA', '20uA', '50uA', '100uA', '200uA', '500uA', '1mA', '2mA', '5mA', '10mA', '20mA', '50mA', '100mA', '200mA', '500mA', '1A'};
            app.Sens2DropDown.Value = '2nA';
            app.All2CheckBox = uicheckbox(app.Lockin2Panel);
            app.All2CheckBox.ValueChangedFcn = createCallbackFcn(app, @All2CheckBoxValueChanged, true);
            app.All2CheckBox.Text = 'All';
            app.All2CheckBox.Value = true;
            app.ItekPanel = uipanel(app.InitialTab);
            app.ItekPanel.Visible = 'off';
            app.U1EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U1CheckBox = uicheckbox(app.ItekPanel);
            app.U1CheckBox.ValueChangedFcn = createCallbackFcn(app, @U1CheckBoxValueChanged, true);
            app.U1CheckBox.Text = 'U1';
            app.U1CheckBox.Value = true;
            app.All3CheckBox = uicheckbox(app.ItekPanel);
            app.All3CheckBox.ValueChangedFcn = createCallbackFcn(app, @All3CheckBoxValueChanged, true);
            app.All3CheckBox.Text = 'All';
            app.All3CheckBox.Value = true;
            app.U2EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U2CheckBox = uicheckbox(app.ItekPanel);
            app.U2CheckBox.ValueChangedFcn = createCallbackFcn(app, @U2CheckBoxValueChanged, true);
            app.U2CheckBox.Text = 'U2';
            app.U2CheckBox.Value = true;
            app.U3EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U3CheckBox = uicheckbox(app.ItekPanel);
            app.U3CheckBox.ValueChangedFcn = createCallbackFcn(app, @U3CheckBoxValueChanged, true);
            app.U3CheckBox.Text = 'U3';
            app.U3CheckBox.Value = true;
            app.U4EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U4CheckBox = uicheckbox(app.ItekPanel);
            app.U4CheckBox.ValueChangedFcn = createCallbackFcn(app, @U4CheckBoxValueChanged, true);
            app.U4CheckBox.Text = 'U4';
            app.U4CheckBox.Value = true;
            app.U5EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U5CheckBox = uicheckbox(app.ItekPanel);
            app.U5CheckBox.ValueChangedFcn = createCallbackFcn(app, @U5CheckBoxValueChanged, true);
            app.U5CheckBox.Text = 'U5';
            app.U5CheckBox.Value = true;
            app.U6EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U6CheckBox = uicheckbox(app.ItekPanel);
            app.U6CheckBox.ValueChangedFcn = createCallbackFcn(app, @U6CheckBoxValueChanged, true);
            app.U6CheckBox.Text = 'U6';
            app.U6CheckBox.Value = true;
            app.U7EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U7CheckBox = uicheckbox(app.ItekPanel);
            app.U7CheckBox.ValueChangedFcn = createCallbackFcn(app, @U7CheckBoxValueChanged, true);
            app.U7CheckBox.Text = 'U7';
            app.U7CheckBox.Value = true;
            app.U8EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U8CheckBox = uicheckbox(app.ItekPanel);
            app.U8CheckBox.ValueChangedFcn = createCallbackFcn(app, @U8CheckBoxValueChanged, true);
            app.U8CheckBox.Text = 'U8';
            app.U8CheckBox.Value = true;
            app.U9EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U9CheckBox = uicheckbox(app.ItekPanel);
            app.U9CheckBox.ValueChangedFcn = createCallbackFcn(app, @U9CheckBoxValueChanged, true);
            app.U9CheckBox.Text = 'U9';
            app.U9CheckBox.Value = true;
            app.U10EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U10CheckBox = uicheckbox(app.ItekPanel);
            app.U10CheckBox.ValueChangedFcn = createCallbackFcn(app, @U10CheckBoxValueChanged, true);
            app.U10CheckBox.Text = 'U10';
            app.U10CheckBox.Value = true;
            app.U11EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U11CheckBox = uicheckbox(app.ItekPanel);
            app.U11CheckBox.ValueChangedFcn = createCallbackFcn(app, @U11CheckBoxValueChanged, true);
            app.U11CheckBox.Text = 'U11';
            app.U11CheckBox.Value = true;
            app.U12EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U12CheckBox = uicheckbox(app.ItekPanel);
            app.U12CheckBox.ValueChangedFcn = createCallbackFcn(app, @U12CheckBoxValueChanged, true);
            app.U12CheckBox.Text = 'U12';
            app.U12CheckBox.Value = true;
            app.U13EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U13CheckBox = uicheckbox(app.ItekPanel);
            app.U13CheckBox.ValueChangedFcn = createCallbackFcn(app, @U13CheckBoxValueChanged, true);
            app.U13CheckBox.Text = 'U13';
            app.U13CheckBox.Value = true;
            app.U14EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U14CheckBox = uicheckbox(app.ItekPanel);
            app.U14CheckBox.ValueChangedFcn = createCallbackFcn(app, @U14CheckBoxValueChanged, true);
            app.U14CheckBox.Text = 'U14';
            app.U14CheckBox.Value = true;
            app.U15EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U15CheckBox = uicheckbox(app.ItekPanel);
            app.U15CheckBox.ValueChangedFcn = createCallbackFcn(app, @U15CheckBoxValueChanged, true);
            app.U15CheckBox.Text = 'U15';
            app.U15CheckBox.Value = true;
            app.U16EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U16CheckBox = uicheckbox(app.ItekPanel);
            app.U16CheckBox.ValueChangedFcn = createCallbackFcn(app, @U16CheckBoxValueChanged, true);
            app.U16CheckBox.Text = 'U16';
            app.U16CheckBox.Value = true;
            app.Ips3Panel = uipanel(app.InitialTab);
            app.Ips3Panel.Visible = 'off';
            app.XtEditField = uieditfield(app.Ips3Panel, 'numeric');
            app.XtCheckBox = uicheckbox(app.Ips3Panel);
            app.XtCheckBox.ValueChangedFcn = createCallbackFcn(app, @XtCheckBoxValueChanged, true);
            app.XtCheckBox.Text = 'X/t';
            app.XtCheckBox.Value = true;
            app.ZtEditField = uieditfield(app.Ips3Panel, 'numeric');
            app.ZtCheckBox = uicheckbox(app.Ips3Panel);
            app.ZtCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZtCheckBoxValueChanged, true);
            app.ZtCheckBox.Text = 'Z/t';
            app.ZtCheckBox.Value = true;
            app.XfEditField = uieditfield(app.Ips3Panel, 'numeric');
            app.XfCheckBox = uicheckbox(app.Ips3Panel);
            app.XfCheckBox.ValueChangedFcn = createCallbackFcn(app, @XfCheckBoxValueChanged, true);
            app.XfCheckBox.Text = 'X';
            app.XfCheckBox.Value = true;
            app.HeatCheckBox = uicheckbox(app.Ips3Panel);
            app.HeatCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeatCheckBoxValueChanged, true);
            app.HeatCheckBox.Text = 'Heat';
            app.HeatCheckBox.Value = true;
            app.HoldCheckBox = uicheckbox(app.Ips3Panel);
            app.HoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldCheckBoxValueChanged, true);
            app.HoldCheckBox.Text = 'Hold';
            app.HoldCheckBox.Value = true;
            app.ToZeroCheckBox = uicheckbox(app.Ips3Panel);
            app.ToZeroCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToZeroCheckBoxValueChanged, true);
            app.ToZeroCheckBox.Text = 'To 0';
            app.ToZeroCheckBox.Value = true;
            app.All4CheckBox = uicheckbox(app.Ips3Panel);
            app.All4CheckBox.ValueChangedFcn = createCallbackFcn(app, @All4CheckBoxValueChanged, true);
            app.All4CheckBox.Text = 'All';
            app.All4CheckBox.Value = true;
            app.ToZeroButton = uibutton(app.Ips3Panel, 'state');
            app.ToZeroButton.ValueChangedFcn = createCallbackFcn(app, @ToZeroButtonValueChanged, true);
            app.ToZeroButton.Text = 'To Zero';
            app.HoldButton = uibutton(app.Ips3Panel, 'state');
            app.HoldButton.ValueChangedFcn = createCallbackFcn(app, @HoldButtonValueChanged, true);
            app.HoldButton.Text = '?';
            app.HeatButton = uibutton(app.Ips3Panel, 'state');
            app.HeatButton.ValueChangedFcn = createCallbackFcn(app, @HeatButtonValueChanged, true);
            app.HeatButton.Text = '?';
            app.ZfPanel = uipanel(app.Ips3Panel);
            app.ZinEditField = uieditfield(app.ZfPanel, 'numeric');
            app.ZinEditField.Editable = 'off';
            app.ZinLabel = uilabel(app.ZfPanel);
            app.ZinLabel.Text = 'Z in';
            app.ZexLabel = uilabel(app.ZfPanel);
            app.ZexLabel.Text = 'Z ex';
            app.ZexEditField = uieditfield(app.ZfPanel, 'numeric');
            app.ZfCheckBox = uicheckbox(app.ZfPanel);
            app.ZfCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZfCheckBoxValueChanged, true);
            app.ZfCheckBox.Text = '';
            app.ZfCheckBox.Value = true;
            app.A33522aPanel = uipanel(app.InitialTab);
            app.A33522aPanel.Title = 'A33522a';
            app.A33522aPanel.Visible = 'off';
            app.All5CheckBox = uicheckbox(app.A33522aPanel);
            app.All5CheckBox.ValueChangedFcn = createCallbackFcn(app, @All5CheckBoxValueChanged, true);
            app.All5CheckBox.Text = 'All';
            app.All5CheckBox.Value = true;
            app.Channel1Panel = uipanel(app.A33522aPanel);
            app.Channel1Panel.Title = 'Channel 1';
            app.Ch1PrdEditField = uieditfield(app.Channel1Panel, 'numeric');
            app.Ch1PrdCheckBox = uicheckbox(app.Channel1Panel);
            app.Ch1PrdCheckBox.Text = 'Prd';
            app.Ch1PrdCheckBox.Value = true;
            app.Ch1AmpEditField = uieditfield(app.Channel1Panel, 'numeric');
            app.Ch1AmpCheckBox = uicheckbox(app.Channel1Panel);
            app.Ch1AmpCheckBox.Text = 'Amp';
            app.Ch1AmpCheckBox.Value = true;
            app.Ch1FreqEditField = uieditfield(app.Channel1Panel, 'numeric');
            app.Ch1FreqCheckBox = uicheckbox(app.Channel1Panel);
            app.Ch1FreqCheckBox.Text = 'Freq';
            app.Ch1FreqCheckBox.Value = true;
            app.Ch1CheckBox = uicheckbox(app.Channel1Panel);
            app.Ch1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch1CheckBoxValueChanged, true);
            app.Ch1CheckBox.Text = 'Ch1';
            app.Ch1CheckBox.Value = true;
            app.Channel2Panel = uipanel(app.A33522aPanel);
            app.Channel2Panel.Title = 'Channel 2';
            app.Ch2CheckBox = uicheckbox(app.Channel2Panel);
            app.Ch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch2CheckBoxValueChanged, true);
            app.Ch2CheckBox.Text = 'Ch2';
            app.Ch2CheckBox.Value = true;
            app.Ch2PrdEditField = uieditfield(app.Channel2Panel, 'numeric');
            app.Ch2PrdCheckBox = uicheckbox(app.Channel2Panel);
            app.Ch2PrdCheckBox.Text = 'Prd';
            app.Ch2PrdCheckBox.Value = true;
            app.Ch2AmpEditField = uieditfield(app.Channel2Panel, 'numeric');
            app.Ch2AmpCheckBox = uicheckbox(app.Channel2Panel);
            app.Ch2AmpCheckBox.Text = 'Amp';
            app.Ch2AmpCheckBox.Value = true;
            app.Ch2FreqEditField = uieditfield(app.Channel2Panel, 'numeric');
            app.Ch2FreqCheckBox = uicheckbox(app.Channel2Panel);
            app.Ch2FreqCheckBox.Text = 'Freq';
            app.Ch2FreqCheckBox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Scan_Init_3R

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end