classdef instrument_setting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        InstrPanel     matlab.ui.container.Panel
        address        matlab.ui.control.DropDown
        VISA_TCPIP     matlab.ui.control.EditField
        GPIB1          matlab.ui.control.NumericEditField
        GPIB2          matlab.ui.control.NumericEditField
        TCPIP1         matlab.ui.control.EditField
        TCPIP2         matlab.ui.control.NumericEditField
        Lamp           matlab.ui.control.Lamp
        INSTRLabel     matlab.ui.control.Label
        VISA_GPIB2     matlab.ui.control.NumericEditField
        VISALabel1     matlab.ui.control.Label
        VISA_GPIB1     matlab.ui.control.NumericEditField
        VISALabel2     matlab.ui.control.Label
        GPIBLabel      matlab.ui.control.Label
        ConnectButton  matlab.ui.control.StateButton
        InstrDrop      matlab.ui.control.DropDown
        SaveButton     matlab.ui.control.Button
        OKButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        InstrInFile = {} % Description
    end
    
    properties (Access = public)
        instrlist = {} % Description
    end
    
    methods (Access = private)
        
        function address_VISA_GPIB_state(app,state)
            app.VISA_GPIB1.Visible = state;
            app.VISA_GPIB2.Visible = state;
            app.VISALabel1.Visible = state;
            app.VISALabel2.Visible = state;
            app.INSTRLabel.Visible = state;
        end
        
        function address_VISA_TCPIP_state(app,state)
            app.VISA_TCPIP.Visible = state;
        end
        
        function address_GPIB_state(app,state)
            app.GPIB1.Visible = state;
            app.GPIB2.Visible = state;
            app.GPIBLabel.Visible = state;
        end
        
        function address_TCPIP_state(app,state)
            app.TCPIP1.Visible = state;
            app.TCPIP2.Visible = state;
        end
        
        function address = read_address(app)
            switch app.address.Value
                case 'VISA-GPIB'
                    address = {'VISA-GPIB',app.VISA_GPIB1.Value,app.VISA_GPIB2.Value};
                case 'VISA-TCPIP'
                    address = {'VISA-TCPIP',app.VISA_TCPIP.Value,80};
                case 'GPIB'
                    address = {'GPIB',app.GPIB1.Value,app.GPIB2.Value};
                case 'TCPIP'
                    address = {'TCPIP',app.TCPIP1.Value,app.TCPIP2.Value};
            end
        end
        
        function write_address(app,str)
            switch str{2}
                case 'VISA-GPIB'
                    app.VISA_GPIB1.Value = str{3};
                    app.VISA_GPIB2.Value = str{4};
                case 'VISA-TCPIP'
                    app.VISA_TCPIP.Value = str{3};
                case 'GPIB'
                    app.GPIB1.Value = str{3};
                    app.GPIB2.Value = str{4};
                case 'TCPIP'
                    app.TCPIP1.Value = str{3};
                    app.TCPIP2.Value = str{4};
            end
        end
        
        function str = instr_format(~,str)
            for i = 1:size(str,1)
                switch str{i,2}
                    case {'VISA-GPIB','GPIB'}
                        str{i,3} = str2double(str{i,3});                        
                end
                str{i,4} = str2double(str{i,4});
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            mainapp.instrlist = '-';
            check_instrlist(mainapp);
            instr = readtable('.\Defaults_para\Defaults_setting\instrlist.txt','Format','%s%s%s%s','Delimiter',',');
            instr = table2cell(instr);
            app.InstrInFile = app.instr_format(instr);
            if ~isempty(instr)
                app.InstrDrop.Value = instr{1};
            else
                app.InstrDrop.Value = '-';
            end
            app.InstrDropValueChanged;
        end

        % Value changed function: address
        function addressValueChanged(app, event)
            app.address_VISA_GPIB_state(0);
            app.address_VISA_TCPIP_state(0);
            app.address_GPIB_state(0);
            app.address_TCPIP_state(0);
            switch app.address.Value
                case 'VISA-GPIB'
                    app.address_VISA_GPIB_state(1);
                case 'VISA-TCPIP'
                    app.address_VISA_TCPIP_state(1);
                case 'GPIB'
                    app.address_GPIB_state(1);
                case 'TCPIP'
                    app.address_TCPIP_state(1);
            end
            
        end

        % Value changed function: InstrDrop
        function InstrDropValueChanged(app, event)
            if strcmp(app.InstrDrop.Value,'-')
                app.InstrPanel.Visible = 0;
                app.SaveButton.Enable = 0;
                app.OKButton.Enable = 0;
            else
                app.InstrPanel.Visible = 1;
                app.SaveButton.Enable = 1;
                app.OKButton.Enable = 1;
                if ~isempty(app.instrlist)
                    [idx,~] = find(strcmp(app.instrlist(:,1),app.InstrDrop.Value));
                    if ~isempty(idx)
                        app.address.Value = app.instrlist{idx,2};
                        app.addressValueChanged;
                        app.write_address(app.instrlist(idx,:));
                        app.ConnectButton.Value = 1;
                        app.ConnectButton.Text = 'DisConnect';
                        app.Lamp.Color = [0 1 0];
                        return;
                    end
                end
                try
                    if strcmp(eval(['app.CallingApp.' app.InstrDrop.Value '.handle.status']),'open')
                        instr = [app.InstrDrop.Value,eval(['app.CallingApp.' app.InstrDrop.Value '.address'])];
                        app.address.Value = instr{2};
                        app.addressValueChanged;
                        app.write_address(instr);
                        app.instrlist = [app.instrlist;instr];
                        app.ConnectButton.Value = 1;
                        app.ConnectButton.Text = 'DisConnect';
                        app.Lamp.Color = [0 1 0];
                        return;
                    end
                catch
                end
                if ~isempty(app.InstrInFile)
                    [idx,~] = find(strcmp(app.InstrInFile(:,1),app.InstrDrop.Value));
                    if ~isempty(idx)
                        app.address.Value = app.InstrInFile{idx,2};
                        app.addressValueChanged;
                        app.write_address(app.InstrInFile(idx,:));
                    end
                end
                app.ConnectButton.Value = 0;
                app.ConnectButton.Text = 'Connect';
                app.Lamp.Color = [0.65 0.65 0.65];
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Enable = 0;
%             app.InstrInFile = [app.instrlist;app.InstrInFile];
%             [~,ia,~] = unique(app.InstrInFile(:,1));
%             app.InstrInFile = app.InstrInFile(ia,:);
            writetable(cell2table(app.instrlist),'.\Defaults_para\Defaults_setting\instrlist.txt','WriteVariableNames',false,'Delimiter',',');
            app.SaveButton.Enable = 1;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if isempty(app.instrlist)
                app.CallingApp.instrlist = '-';
            else
                app.CallingApp.instrlist = ['-';app.instrlist(:,1)];
            end
            check_instrlist(app.CallingApp);
            app.CallingApp.InstrumentsSettingButton.Enable = 1;
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app);
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            import instrument.*;
            import tool.*;
            import real_instrument.*;
            
            if app.ConnectButton.Value
                addr = read_address(app);
                if ~isempty(app.instrlist)
                    [idx,~] = find(strcmp(app.instrlist(:,1),app.InstrDrop.Value));
                    if ~isempty(idx)
                        if isequal(app.instrlist(idx,:),[app.InstrDrop.Value,addr])
                            app.ConnectButton.Value = 1;
                            app.ConnectButton.Text = 'DisConnect';
                            app.Lamp.Color = [0 1 0];
                            return;
                        end
                    end
                end
                try
                    switch app.InstrDrop.Value
                        case 'lockin1'
                            app.CallingApp.lockin1 = instr_SR830(addr,'.\Defaults_para\Defaults_setting\instr_para\lockin1.txt');
                        case 'lockin2'
                            app.CallingApp.lockin2 = instr_SR830(addr,'.\Defaults_para\Defaults_setting\instr_para\lockin2.txt');
                        case 'itek'
                            app.CallingApp.itek = instr_ITEK(addr,'.\Defaults_para\Defaults_setting\instr_para\itek.txt');
                        case 'dmm'
                            app.CallingApp.dmm = instr_KEITHLEY2015(addr,'.\Defaults_para\Defaults_setting\instr_para\dmm.txt');
                        case 'dmm2'
                            app.CallingApp.dmm2 = instr_KEITHLEY2015(addr,'.\Defaults_para\Defaults_setting\instr_para\dmm2.txt');
                        case 'a33522a'
                            app.CallingApp.a33522a = instr_A33522A(addr,'.\Defaults_para\Defaults_setting\instr_para\a33522a.txt');
                    end
                catch exception
                    app.ConnectButton.Value = 0;
                    uialert(app.UIFigure,exception.message,exception.identifier);
                    return;
                end
                app.instrlist = [app.InstrDrop.Value,addr;app.instrlist];
                [~,ia,~] = unique(app.instrlist(:,1));
                app.instrlist = app.instrlist(ia,:);
                app.ConnectButton.Text = 'DisConnect';
                app.Lamp.Color = [0 1 0];
            else
                try
                    switch app.InstrDrop.Value
                        case 'lockin1'
                            app.CallingApp.lockin1.delete;
                        case 'lockin2'
                            app.CallingApp.lockin2.delete;
                        case 'itek'
                            app.CallingApp.itek.delete;
                        case 'dmm'
                            app.CallingApp.dmm.delete;
                        case 'a33522a'
                            app.CallingApp.a33522a.delete;
                        case 'ips'
                            app.CallingApp.ips.delete;
                    end
                catch exception
                    uialert(app.UIFigure,exception.message,exception.identifier);
                end
                [~,ia] = setdiff(app.instrlist(:,1),app.InstrDrop.Value);
                app.instrlist = app.instrlist(ia,:);
                app.ConnectButton.Text = 'Connect';
                app.Lamp.Color = [0.65 0.65 0.65];
            end
            
        end

        % Value changed function: GPIB1
        function GPIB1ValueChanged(app, event)
                app.ConnectButton.Value = 0;
                app.ConnectButton.Text = 'Connect';
                app.Lamp.Color = [0.65 0.65 0.65];
        end

        % Value changed function: GPIB2
        function GPIB2ValueChanged(app, event)
            app.ConnectButton.Value = 0;
            app.ConnectButton.Text = 'Connect';
            app.Lamp.Color = [0.65 0.65 0.65];
        end

        % Value changed function: VISA_GPIB1
        function VISA_GPIB1ValueChanged(app, event)
            app.ConnectButton.Value = 0;
            app.ConnectButton.Text = 'Connect';
            app.Lamp.Color = [0.65 0.65 0.65];
        end

        % Value changed function: VISA_GPIB2
        function VISA_GPIB2ValueChanged(app, event)
            app.ConnectButton.Value = 0;
            app.ConnectButton.Text = 'Connect';
            app.Lamp.Color = [0.65 0.65 0.65];
        end

        % Value changed function: TCPIP1
        function TCPIP1ValueChanged(app, event)
            app.ConnectButton.Value = 0;
            app.ConnectButton.Text = 'Connect';
            app.Lamp.Color = [0.65 0.65 0.65];
        end

        % Value changed function: TCPIP2
        function TCPIP2ValueChanged(app, event)
            app.ConnectButton.Value = 0;
            app.ConnectButton.Text = 'Connect';
            app.Lamp.Color = [0.65 0.65 0.65];
        end

        % Value changed function: VISA_TCPIP
        function VISA_TCPIPValueChanged(app, event)
            app.ConnectButton.Value = 0;
            app.ConnectButton.Text = 'Connect';
            app.Lamp.Color = [0.65 0.65 0.65];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InstrPanel = uipanel(app.UIFigure);
            app.address = uidropdown(app.InstrPanel);
            app.address.Items = {' VISA-GPIB', 'VISA-TCPIP', '       GPIB', '      TCPIP'};
            app.address.ItemsData = {'VISA-GPIB', 'VISA-TCPIP', 'GPIB', 'TCPIP'};
            app.address.ValueChangedFcn = createCallbackFcn(app, @addressValueChanged, true);
            app.address.Tooltip = {'transmision protocol'};
            app.address.Value = 'VISA-GPIB';
            app.VISA_TCPIP = uieditfield(app.InstrPanel, 'text');
            app.VISA_TCPIP.ValueChangedFcn = createCallbackFcn(app, @VISA_TCPIPValueChanged, true);
            app.VISA_TCPIP.Visible = 'off';
            app.VISA_TCPIP.Tooltip = {'e.g. 192.168.1.1'};
            app.GPIB1 = uieditfield(app.InstrPanel, 'numeric');
            app.GPIB1.ValueDisplayFormat = '%.0f';
            app.GPIB1.ValueChangedFcn = createCallbackFcn(app, @GPIB1ValueChanged, true);
            app.GPIB1.Visible = 'off';
            app.GPIB1.Tooltip = {'e.g. 7'};
            app.GPIB2 = uieditfield(app.InstrPanel, 'numeric');
            app.GPIB2.ValueDisplayFormat = '%.0f';
            app.GPIB2.ValueChangedFcn = createCallbackFcn(app, @GPIB2ValueChanged, true);
            app.GPIB2.Visible = 'off';
            app.GPIB2.Tooltip = {'e.g. 10'};
            app.TCPIP1 = uieditfield(app.InstrPanel, 'text');
            app.TCPIP1.ValueChangedFcn = createCallbackFcn(app, @TCPIP1ValueChanged, true);
            app.TCPIP1.Visible = 'off';
            app.TCPIP1.Tooltip = {'e.g. 192.168.1.1'};
            app.TCPIP2 = uieditfield(app.InstrPanel, 'numeric');
            app.TCPIP2.ValueDisplayFormat = '%.0f';
            app.TCPIP2.ValueChangedFcn = createCallbackFcn(app, @TCPIP2ValueChanged, true);
            app.TCPIP2.Visible = 'off';
            app.TCPIP2.Tooltip = {'e.g. 80'};
            app.TCPIP2.Value = 80;
            app.Lamp = uilamp(app.InstrPanel);
            app.INSTRLabel = uilabel(app.InstrPanel);
            app.INSTRLabel.Text = ':: INSTR';
            app.VISA_GPIB2 = uieditfield(app.InstrPanel, 'numeric');
            app.VISA_GPIB2.ValueDisplayFormat = '%.0f';
            app.VISA_GPIB2.ValueChangedFcn = createCallbackFcn(app, @VISA_GPIB2ValueChanged, true);
            app.VISA_GPIB2.Value = 10;
            app.VISALabel1 = uilabel(app.InstrPanel);
            app.VISALabel1.Text = '::';
            app.VISA_GPIB1 = uieditfield(app.InstrPanel, 'numeric');
            app.VISA_GPIB1.ValueDisplayFormat = '%.0f';
            app.VISA_GPIB1.ValueChangedFcn = createCallbackFcn(app, @VISA_GPIB1ValueChanged, true);
            app.VISALabel2 = uilabel(app.InstrPanel);
            app.VISALabel2.Text = 'GPIB';
            app.GPIBLabel = uilabel(app.InstrPanel);
            app.GPIBLabel.Visible = 'off';
            app.GPIBLabel.Text = '，';
            app.ConnectButton = uibutton(app.InstrPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.InstrDrop = uidropdown(app.UIFigure);
            app.InstrDrop.Items = {' lockin 1', ' lockin 2', ' itek', ' dmm', ' a33522a', ' ips', '-'};
            app.InstrDrop.ItemsData = {'lockin1', 'lockin2', 'itek', 'dmm', 'a33522a', 'ips', '-'};
            app.InstrDrop.ValueChangedFcn = createCallbackFcn(app, @InstrDropValueChanged, true);
            app.InstrDrop.Tooltip = {'choose instruments'};
            app.InstrDrop.Value = '-';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'save config as default'};
            app.SaveButton.Text = 'Save';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'save config as default'};
            app.OKButton.Text = 'OK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = instrument_setting(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end